classdef FL_smFRET_smPIFE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreviouslyanalyzedfileButton    matlab.ui.control.Button
        FilenameLabel                   matlab.ui.control.Label
        Gauge                           matlab.ui.control.LinearGauge
        ContinueButton                  matlab.ui.control.Button
        iterationsEditField             matlab.ui.control.NumericEditField
        iterationsEditFieldLabel        matlab.ui.control.Label
        RunPanel                        matlab.ui.container.Panel
        BurninEditField                 matlab.ui.control.NumericEditField
        BurninEditFieldLabel            matlab.ui.control.Label
        SavesizeEditField               matlab.ui.control.NumericEditField
        SavesizeEditFieldLabel          matlab.ui.control.Label
        ParametersPanel                 matlab.ui.container.Panel
        FRETstatesEditField             matlab.ui.control.NumericEditField
        ofFRETstatesLabel               matlab.ui.control.Label
        acceptorPIFEstatesEditField     matlab.ui.control.NumericEditField
        ofacceptorPIFEstatesLabel       matlab.ui.control.Label
        donorPIFEstatesEditField        matlab.ui.control.NumericEditField
        ofdonorPIFEstatesLabel          matlab.ui.control.Label
        FLsmFRETsmPIFEv11writtenbySinaJazanisjazani1jhueduLabel  matlab.ui.control.Label
        HelpButton                      matlab.ui.control.Button
        LicenseButton                   matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        SyntheticdatasetparametersPanel  matlab.ui.container.Panel
        overlineoverlineQ_AEditField    matlab.ui.control.EditField
        overlineoverlineQ_AEditFieldLabel  matlab.ui.control.Label
        overlineoverlineQ_DEditField    matlab.ui.control.EditField
        overlineoverlineQ_DEditFieldLabel  matlab.ui.control.Label
        overlineoverlineQ_FEditField    matlab.ui.control.EditField
        overlineoverlineQ_FEditFieldLabel  matlab.ui.control.Label
        mu_textbackAphtssEditField      matlab.ui.control.EditField
        mu_textbackAphtssEditFieldLabel  matlab.ui.control.Label
        mu_textbackDphtssEditField      matlab.ui.control.EditField
        mu_textbackDphtssEditFieldLabel  matlab.ui.control.Label
        mu_textexDexcpulseEditField     matlab.ui.control.EditField
        mu_textexDexcpulseEditFieldLabel  matlab.ui.control.Label
        mu_textexAexcpulseEditField     matlab.ui.control.EditField
        mu_textexAexcpulseEditFieldLabel  matlab.ui.control.Label
        K_textFRETns1EditField          matlab.ui.control.EditField
        K_textFRETns1EditFieldLabel     matlab.ui.control.Label
        tau_textDnsEditField            matlab.ui.control.EditField
        tau_textDnsEditFieldLabel       matlab.ui.control.Label
        tau_textAnsEditField            matlab.ui.control.EditField
        tau_textALabel                  matlab.ui.control.Label
        eta_textAEditField              matlab.ui.control.NumericEditField
        eta_textAEditFieldLabel         matlab.ui.control.Label
        eta_textDEditField              matlab.ui.control.NumericEditField
        eta_textDEditFieldLabel         matlab.ui.control.Label
        KnownparametersPanel            matlab.ui.container.Panel
        NumberphotonsEditField          matlab.ui.control.NumericEditField
        NumberphotonsEditFieldLabel     matlab.ui.control.Label
        LengthsEditField                matlab.ui.control.NumericEditField
        LengthsEditFieldLabel           matlab.ui.control.Label
        sigma_textIRFAnsEditField       matlab.ui.control.NumericEditField
        sigma_textIRFAnsEditFieldLabel  matlab.ui.control.Label
        mu_textIRFAnsEditField          matlab.ui.control.NumericEditField
        mu_textIRFAnsEditFieldLabel     matlab.ui.control.Label
        sigma_textIRFDnsEditField       matlab.ui.control.NumericEditField
        sigma_textIRFDLabel             matlab.ui.control.Label
        mu_textIRFDnsEditField          matlab.ui.control.NumericEditField
        mu_IRFDLabel                    matlab.ui.control.Label
        PulseperiodnsEditField          matlab.ui.control.NumericEditField
        PulseperiodnsEditFieldLabel     matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        GenerateButton                  matlab.ui.control.Button
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
    end
    
    methods (Access = private)
        

%% Generative model
%
%  This code is written by Sina Jazani (03/28/2023)
%  Contact: sjazani1@jhu.edu
function[ Data ]   = Generative_model(app, Data)
% Erase the Data vectors just in case! 
Data.signal             = []; Data.PI_real            = [];
Data.F_fluc_real        = []; Data.tau_D_fluc_real    = []; Data.tau_A_fluc_real    = [];     


% Pre localize the space
Num_states_F            = length(Data.KF_real);
Num_states_D            = length(Data.KD_real);
Num_states_A            = length(Data.KA_real);

% Transition probabilities of FRET and PIFE
if  sum(diag(Data.KF_real)+sum(Data.KF_real-diag(diag(Data.KF_real)),1)')==0
    TF                  = expm((Data.KF_real)*Data.pulse_priod*10^-9);
else
    msgbox("The input value is not correct. We normalize the FRET transition matrix!","Not normalized FRET transition matrix!","error");
    TF                  = expm((Data.KF_real-diag(diag(Data.KF_real))-diag(sum(Data.KF_real-diag(diag(Data.KF_real)),1)))*Data.pulse_priod*10^-9);
end
if  sum(diag(Data.KD_real)+sum(Data.KD_real-diag(diag(Data.KD_real)),1)')==0
    TD                  = expm((Data.KD_real)*Data.pulse_priod*10^-9);
else
    msgbox("The input value is not correct. We normalize the donor-PIFE transition matrix!","Not normalized donor-PIFE transition matrix!","error");
    TD                  = expm((Data.KD_real-diag(diag(Data.KD_real))-diag(sum(Data.KD_real-diag(diag(Data.KD_real)),1)))*Data.pulse_priod*10^-9);
end
if  sum(diag(Data.KA_real)+sum(Data.KA_real-diag(diag(Data.KA_real)),1)')==0
    TA                  = expm((Data.KA_real)*Data.pulse_priod*10^-9);
else
    msgbox("The input value is not correct. We normalize the acceptor-PIFE transition matrix!","Not normalized acceptor-PIFE transition matrix!","error");
    TA                  = expm((Data.KA_real-diag(diag(Data.KA_real))-diag(sum(Data.KA_real-diag(diag(Data.KA_real)),1)))*Data.pulse_priod*10^-9);
end

F_state(1)              = Discrete_sampler(app,ones(1,Num_states_F)./Num_states_F);
D_state(1)              = Discrete_sampler(app,ones(1,Num_states_D)./Num_states_D);
A_state(1)              = Discrete_sampler(app,ones(1,Num_states_A)./Num_states_A);

for ii=2:Data.Num_pulses
    F_state(ii,1)       = find(rand(1)<=cumsum(TF(:,F_state(ii-1,1))), 1 );
    D_state(ii,1)       = find(rand(1)<=cumsum(TD(:,D_state(ii-1,1))), 1 );
    A_state(ii,1)       = find(rand(1)<=cumsum(TA(:,A_state(ii-1,1))), 1 );
end

F_fluc_real             = Data.KF_val_real(1,F_state)                     ;
tau_D_fluc_real         = Data.KD_val_real(1,D_state)                     ;
tau_A_fluc_real         = Data.KA_val_real(1,A_state)                     ;

% Calculate the weights over each pulse. This weight is not normalized and sum of it is equal to the photon detection probability for each pulse.
pii1(1,:)               = exp(-Data.ex_D_real(1,D_state));
pii2(1,:)               = exp(-Data.ex_A_real(1,A_state));
pii3                    = exp(-Data.pulse_priod*Data.Back_D_real.*(10^-9));
pii4                    = exp(-Data.pulse_priod*Data.Back_A_real.*(10^-9));
PII                     = [ (1-pii1).*pii2.*pii3*pii4;(1-pii2).*pii1.*pii3*pii4;(1-pii3).*pii1.*pii2*pii4;(1-pii4).*pii1.*pii2*pii3 ];  

% Sample the events of photon detetction for each excitation pulse
Active_pulses           = sum(PII,1)>rand(1,Data.Num_pulses);

% Calculate the weight on the source of the detected photons
Data.PI_real            = PII(:,Active_pulses)./sum(PII(:,Active_pulses),1);

% Number of detcted photons
num_photons              = size(Data.PI_real,2);

% Sample the source of the photons
PPo                     = cumsum(Data.PI_real,1);
Events                  = 1+sum((repmat(rand(1,num_photons),4,1) <= PPo)==0,1);

% Save the FRET and PIFE states of the molecule at the time we detect the photons
Data.F_fluc_real        = F_fluc_real(1,Active_pulses)    ;
Data.tau_D_fluc_real    = tau_D_fluc_real(1,Active_pulses);
Data.tau_A_fluc_real    = tau_A_fluc_real(1,Active_pulses);
% Pre caculation of the KT
K_T                     = (Data.F_fluc_real)+(1./Data.tau_D_fluc_real);

% Order of pulses
pulses                  = 1:Data.Num_pulses;

% The time photons are detected
ii                      = pulses(Active_pulses)*Data.pulse_priod*10^-9;

for i=1:num_photons
    app.Gauge.Value = 10+90*i./num_photons;
    drawnow;
    switch Events(i)
        case 1 % Donor excitation
             % FRET events 
             FRET = Discrete_sampler(app,[1./Data.tau_D_fluc_real(i),Data.F_fluc_real(i)]);  
             
             if  FRET==1  % Donor excitation-> Donor emission
                 if   Data.eta_D_real>rand() % Acceptor detection
                      Data.signal(:,i)  = [(exprnd(1/K_T(i))+Data.IRF_mean(2)+Data.IRF_str(2)*randn());ii(i);2];
                 else                        % Donor detection
                      Data.signal(:,i)  = [(exprnd(1/K_T(i))+Data.IRF_mean(1)+Data.IRF_str(1)*randn());ii(i);1];
                 end
             else     % Donor excitaiton -> FRET -> Acceptor emission
                 if   Data.eta_A_real>rand() % Donor detection
                      Data.signal(:,i)  = [(exprnd(1/K_T(i))+exprnd(Data.tau_A_fluc_real(i))+Data.IRF_mean(1)+Data.IRF_str(1)*randn());ii(i);1];
                 else                        % Acceptor detection
                      Data.signal(:,i)  = [(exprnd(1/K_T(i))+exprnd(Data.tau_A_fluc_real(i))+Data.IRF_mean(2)+Data.IRF_str(2)*randn());ii(i);2];
                 end
             end
        case 2  % Acceptor excitation
            if   Data.eta_A_real>rand() % Donor detection
                 Data.signal(:,i)  = [(exprnd(Data.tau_A_fluc_real(i))+Data.IRF_mean(1)+Data.IRF_str(1)*randn());ii(i);1];
            else                        % Acceptor detection
                 Data.signal(:,i)  = [(exprnd(Data.tau_A_fluc_real(i))+Data.IRF_mean(2)+Data.IRF_str(2)*randn());ii(i);2];
            end
        case 3   % Donor background
            if   Data.eta_D_real>rand() % Acceptor detection
                 Data.signal(:,i)  = [Data.pulse_priod*rand();ii(i);2];
            else                        % Donor detection
                 Data.signal(:,i)  = [Data.pulse_priod*rand();ii(i);1];
            end
        case 4   % Acceptor background
            if   Data.eta_A_real>rand() % Donor detection
                 Data.signal(:,i)  = [Data.pulse_priod*rand();ii(i);1];
            else                        % Acceptor detection
                 Data.signal(:,i)  = [Data.pulse_priod*rand();ii(i);2];
            end
    end
end
end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%% Initializing the parameters
%
%  This code is written by Sina Jazani (03/28/2023)
%  Contact: sjazani1@jhu.edu
function[ Data ] = Initiation_param(app,Data)

Data.F_all_base            = ones(Data.Num_states_F,1)./Data.Num_states_F;
Data.F_all                 = dirichletRnd(app,Data.F_all_alpha*Data.F_all_base) ;


Data.D_all_base            = ones(Data.Num_states_D,1)./Data.Num_states_D;
Data.D_all                 = dirichletRnd(app,Data.D_all_alpha*Data.D_all_base) ;

Data.A_all_base            = ones(Data.Num_states_A,1)./Data.Num_states_A;
Data.A_all                 = dirichletRnd(app,Data.A_all_alpha*Data.A_all_base) ;

Data.sigs_size             = []; Data.F_res                 = []; Data.tau_D_res             = []; 
Data.tau_A_res             = []; Data.KF_val_learned        = []; Data.KD_val_learned        = []; 
Data.KA_val_learned        = []; Data.KF_rate_learned       = []; Data.KD_rate_learned       = []; 
Data.KA_rate_learned       = []; Data.mu_D_ex_learned       = []; Data.mu_A_ex_learned       = [];
Data.mu_BD_learned         = []; Data.mu_BA_learned         = []; 


% Number of individual time traces
Data.num_sub_sigs = length(Data.signals);

% Constructing of the transition matrix
Data.KF_rate_learned     = gamrnd(Data.KF_rate_alpha ,Data.KF_rate_beta ,Data.Num_states_F,Data.Num_states_F).*(ones(Data.Num_states_F)-eye(Data.Num_states_F)) ;
Data.KD_rate_learned     = gamrnd(Data.KD_rate_alpha ,Data.KD_rate_beta ,Data.Num_states_D,Data.Num_states_D).*(ones(Data.Num_states_D)-eye(Data.Num_states_D)) ;
Data.KA_rate_learned     = gamrnd(Data.KA_rate_alpha ,Data.KA_rate_beta ,Data.Num_states_A,Data.Num_states_A).*(ones(Data.Num_states_A)-eye(Data.Num_states_A)) ;

mmtF                     = Data.KF_rate_learned-diag(sum(Data.KF_rate_learned,1));
mmtD                     = Data.KD_rate_learned-diag(sum(Data.KD_rate_learned,1));
mmtA                     = Data.KA_rate_learned-diag(sum(Data.KA_rate_learned,1));

for mmk=1:Data.num_sub_sigs
    
    Data.KA_val_learned{mmk}        = gamrnd(Data.KA_val_alpha   , Data.KA_val_beta   , 1,Data.Num_states_A) ;
    Data.KD_val_learned{mmk}        = gamrnd(Data.KD_val_alpha   , Data.KD_val_beta   , 1,Data.Num_states_D) ;
    Data.KF_val_learned{mmk}        = gamrnd(Data.KF_val_alpha   , Data.KF_val_beta   , 1,Data.Num_states_F) ;
    
    Data.mu_D_ex_learned{mmk}(1,:)  = gamrnd(Data.alpha_mu_D_ex  , Data.beta_mu_D_ex  , 1 , Data.Num_states_D ) ;
    Data.mu_A_ex_learned{mmk}(1,:)  = gamrnd(Data.alpha_mu_A_ex  , Data.beta_mu_A_ex  , 1 , Data.Num_states_A ) ;
    
    Data.mu_BD_learned(mmk,1)       = gamrnd(Data.alpha_mu_BD    , Data.beta_mu_BD    )                ;
    Data.mu_BA_learned(mmk,1)       = gamrnd(Data.alpha_mu_BA    , Data.beta_mu_BA    )                ;
    
    Data.F_res{mmk}(1)              = Discrete_sampler(app,Data.F_all)                                ;
    Data.tau_D_res{mmk}(1)          = Discrete_sampler(app,Data.D_all)                                ;
    Data.tau_A_res{mmk}(1)          = Discrete_sampler(app,Data.A_all)                                ;
    
    Data.sigs_size(mmk)             = size(Data.signals{mmk},2)                                ;
    for jl=1:Data.sigs_size(mmk)-1
        TA                          = expm(mmtA*(Data.signals{mmk}(2,jl+1)-Data.signals{mmk}(2,jl))) ;
        Data.tau_A_res{mmk}(1,jl+1) = Discrete_sampler(app,TA(:,Data.tau_A_res{mmk}(jl)))                ;
        TF                          = expm(mmtF*(Data.signals{mmk}(2,jl+1)-Data.signals{mmk}(2,jl))) ;
        Data.F_res{mmk}(1,jl+1)     = Discrete_sampler(app,TF(:,Data.F_res{mmk}(jl)))                ;
        TD                          = expm(mmtD*(Data.signals{mmk}(2,jl+1)-Data.signals{mmk}(2,jl))) ;
        Data.tau_D_res{mmk}(1,jl+1) = Discrete_sampler(app,TD(:,Data.tau_D_res{mmk}(jl)))                ;
    end
end

Data.tau_accept                     = zeros(2,Data.num_sub_sigs)  ;
Data.pi_accept                      = zeros(2,Data.num_sub_sigs)  ;
Data.eta_accept                     = [0 ; 0]  ;


% Set the values of MAP estimates
Data.map             = -Inf                            ; Data.RAW_map         = Data.map                        ;
Data.map_KF_rate     = Data.KF_rate_learned(:,end)     ; Data.map_KD_rate     = Data.KD_rate_learned(:,end)     ;
Data.map_KA_rate     = Data.KA_rate_learned(:,end)     ; Data.map_F_all       = Data.F_all(:,end)               ; 
Data.map_D_all       = Data.D_all(:,end)               ; Data.map_A_all       = Data.A_all(:,end)               ;
Data.map_KA_val      = Data.KA_val_learned             ; Data.map_KD_val      = Data.KD_val_learned             ;
Data.map_KF_val      = Data.KF_val_learned             ; Data.map_F_res       = Data.F_res                      ; 
Data.map_tau_D       = Data.tau_D_res                  ; Data.map_tau_A       = Data.tau_A_res                  ;
Data.map_mu_D_ex     = Data.mu_D_ex_learned            ; Data.map_mu_A_ex     = Data.mu_A_ex_learned            ;
Data.map_mu_BD       = Data.mu_BD_learned              ; Data.map_mu_BA       = Data.mu_BA_learned              ;
Data.map_eta_D       = Data.eta_D_learned(end)         ; Data.map_eta_A       = Data.eta_A_learned(end)         ; 

end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%% The MCMC chain (Gibbs sampler) for single photon FRET+PIFE analysis
%  This function is main sampler
%
%  This code is written by Sina Jazani (03/28/2023)
%  Contact: sjazani1@jhu.edu

function[Data] = Gibbs_sampler(app,Data , Max_iter)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% INPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % Data                  :  A structure array contains of all parameters and variables
   % Max_iter              :  Number of iteration per run
   
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% OUTPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % Data                  :  The updatetd structure array contains of all parameters and variables
   
   % Shuffle the random generator
   rng('shuffle');

% Initiation of the parameters for the Parallel loop and Gibbs sampler
   [  tester            , map               , KF_rate_learned   , KD_rate_learned   , ...
      IRF_D_str2        , IRF_A_str2        , eta_A_learned     , signal_time       , ...
      sigs_size         , eta_D_learned     , mu_D_ex_learned   , mu_A_ex_learned   , ...
      mu_BD_learned     , mu_BA_learned     , pi_accept         , prop_mu_D_ex      , ...
      alpha_mu_D_ex     , beta_mu_D_ex      , prop_mu_A_ex      , alpha_mu_A_ex     , ...
      beta_mu_A_ex      , max_bin           , prop_mu_BD        , alpha_mu_BD       , ...
      beta_mu_BD        , prop_mu_BA        , alpha_mu_BA       , beta_mu_BA        , ...
      KF_val_prop       , KF_val_alpha      , KF_val_beta       , AII               , ...
      A_all             , sigg11            , KD_val_prop       , KD_val_alpha      , ...
      KD_val_beta       , KD_val_learned    , KF_val_learned    , sigg22            , ...
      FII               , DII               , F_res             , tau_D_res         , ...
      indx1             , indx2             , sigg1             , sigg2             , ...
      pi_learned        , KF_rate_alpha     , KF_rate_beta      , Num_states_F      , ...
      KD_rate_alpha     , KD_rate_beta      , Num_states_D      , F_all             , ...
      D_all             , F_all_alpha       , F_all_base        , D_all_alpha       , ...
      D_all_base        , Num_states_A      , KA_rate_alpha     , KA_rate_beta      , ...
      KA_val_prop       , KA_val_alpha      , KA_val_beta       , KA_rate_learned   , ...
      A_all_alpha       , A_all_base        , tau_A_res         , KA_val_learned    , ...
      IRFDDstr2         , IRFAAstr2         ]= Initiation_Gibbs(app,Data);
   
   % Start of the MCMC chain, Gibbs sampling iteration FOR loop
   for  i=1:Max_iter
       
        for mmk=1:Data.num_sub_sigs
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           %%%%%%%%           Sample the FRET state trajectory            %%%%%%%%%%%
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           if Num_states_F>1
              [ F_res{mmk}  ] = sampling_FRET_HMM( ...
                 ... 
              app,sigs_size(mmk)   , IRF_D_str2       , IRF_A_str2     , FII{mmk}               , ...
              KA_val_learned(mmk,:), tau_D_res{mmk}   , eta_D_learned  , KF_val_learned(mmk,:)' , ...
              eta_A_learned        , pi_learned{mmk}  , max_bin        , KD_val_learned(mmk,:)  , ...
              indx1{mmk}           , indx2{mmk}       , sigg1{mmk}     , sigg2{mmk}             , ...
              F_all                , Num_states_F     , tau_A_res{mmk} , IRFDDstr2              , ...
              IRFAAstr2            , sigg11{mmk}      , sigg22{mmk}   );
           end
        

           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           %%%%%%%%      Sample the acceptor PIFE state trajectory       %%%%%%%%%%%
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           if Num_states_A>1
              [ tau_A_res{mmk}   ] = sampling_tau_A_HMM( ...
                  ... 
              app,sigs_size(mmk)   , IRF_D_str2           , IRF_A_str2           , KA_val_learned(mmk,:)' , ...
              F_res{mmk}           , eta_D_learned        , eta_A_learned        , max_bin                , ...
              mu_D_ex_learned{mmk} , mu_A_ex_learned{mmk} , mu_BD_learned(mmk,1) , tau_D_res{mmk}         , ...
              mu_BA_learned(mmk,1) , KD_val_learned(mmk,:), KF_val_learned(mmk,:), AII{mmk}               , ...
              indx1{mmk}           , indx2{mmk}           , sigg1{mmk}           , sigg2{mmk}             , ...
              A_all                , Num_states_A         , IRFDDstr2            , IRFAAstr2              , ...
              sigg11{mmk}          , sigg22{mmk}          );
           end


           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           %%%%%%%%        Sample the donor PIFE state trajectory         %%%%%%%%%%%
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           if Num_states_D>1
              [ tau_D_res{mmk}     , pi_learned{mmk} ] = sampling_tau_D_HMM( ...
                 ... 
              app,sigs_size(mmk)   , IRF_D_str2            , IRF_A_str2            , KA_val_learned(mmk,:) , ...
              F_res{mmk}           , eta_D_learned         , eta_A_learned         , max_bin               , ...
              mu_D_ex_learned{mmk} , mu_A_ex_learned{mmk}  , mu_BD_learned(mmk,1)  , tau_A_res{mmk}        , ...
              mu_BA_learned(mmk,1) , KD_val_learned(mmk,:)', KF_val_learned(mmk,:) , DII{mmk}              , ...
              indx1{mmk}           , indx2{mmk}            , sigg1{mmk}            , sigg2{mmk}            , ...
              D_all                , Num_states_D          , IRFDDstr2             , IRFAAstr2             , ...
              sigg11{mmk}          , sigg22{mmk}          );
           end


           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           %%%%%%       Sample the donor and ccceptor excitation rates         %%%%%%%
           %%%%%%                            and                               %%%%%%%  
           %%%%%%  donor and acceptor channel background photon emission rates %%%%%%%
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           [mu_D_ex_learned{mmk} , mu_A_ex_learned{mmk} , mu_BD_learned(mmk,1) , mu_BA_learned(mmk,1) , ...
            pi_learned{mmk}      , pi_accept(:,mmk)     , log_like(1,mmk)                             ] = sampling_pi_tau( ...
                 ... 
           app,pi_accept(:,mmk)  , IRF_D_str2           , IRF_A_str2           , F_res{mmk}           , ...
           tau_D_res{mmk}        , KA_val_learned(mmk,:), eta_D_learned        , eta_A_learned        , ...
           max_bin               , mu_D_ex_learned{mmk} , mu_A_ex_learned{mmk} , mu_BD_learned(mmk,1) , ...
           mu_BA_learned(mmk,1)  , pi_learned{mmk}      , prop_mu_D_ex         , alpha_mu_D_ex        , ...
           beta_mu_D_ex          , prop_mu_A_ex         , alpha_mu_A_ex        , beta_mu_A_ex         , ...
           prop_mu_BD            , alpha_mu_BD          , beta_mu_BD           , prop_mu_BA           , ...
           alpha_mu_BA           , beta_mu_BA           , KD_val_learned(mmk,:), KF_val_learned(mmk,:), ...
           indx1{mmk}            , indx2{mmk}           , sigg1{mmk}           , sigg2{mmk}           , ...
           Num_states_D          , Num_states_A         , tau_A_res{mmk}       , IRFDDstr2            , ...
           IRFAAstr2             , sigg11{mmk}          , sigg22{mmk}         );
       end
     
        [log_like , KA_val_learned  , KD_val_learned  , KF_val_learned  ] = sampling_pi_tau1( ...
                 ... 
        app,IRF_D_str2      , IRF_A_str2       , F_res               , tau_D_res       , ...
        KA_val_learned      , eta_D_learned    , eta_A_learned       , max_bin         , ...
        KD_val_learned      , KF_val_learned   , pi_learned          , indx1           , ...
        indx2               , sigg1            , sigg2               , Num_states_D    , ...
        Num_states_A        , tau_A_res        , KA_val_prop         , KA_val_alpha    , ...
        KA_val_beta         , KF_val_prop      , KF_val_alpha        , KF_val_beta     , ...
        KD_val_prop         , KD_val_alpha     , KD_val_beta         , Num_states_F    , ...
        log_like            , IRFDDstr2        , IRFAAstr2           , sigg11          , ...
        sigg22              );


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%         Sample FRET state transition rates         %%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        if  Num_states_F==1
            KF_log_like = 0;
        else
            [ KF_rate_learned , FII     , KF_log_like ] = sampling_Transit_rates( ...
             ...
            app,F_res       , KF_rate_alpha  , KF_rate_beta  , sigs_size          , signal_time  , ...
            KF_rate_learned , FII            , Num_states_F  , Data.num_sub_sigs );
         
            F_all  = sampling_rate_all( app,F_res  , Num_states_F   , F_all_alpha    , F_all_base  , Data.num_sub_sigs );
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%        Sample donor PIFE state transition rates        %%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        if  Num_states_D==1
            KD_log_like = 0;
        else
            [ KD_rate_learned , DII      , KD_log_like  ] = sampling_Transit_rates( ...
             ...
            app,tau_D_res   , KD_rate_alpha  , KD_rate_beta  , sigs_size          , signal_time  , ...
            KD_rate_learned , DII            , Num_states_D  , Data.num_sub_sigs  );
        
            D_all  = sampling_rate_all( app,tau_D_res , Num_states_D  , D_all_alpha    , D_all_base    , Data.num_sub_sigs );
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%       Sample acceptor PIFE state transition rates       %%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        if   Num_states_A==1
             KA_log_like = 0;
        else
            [ KA_rate_learned , AII        , KA_log_like  ] = sampling_Transit_rates( ...
             ...
             app,tau_A_res   , KA_rate_alpha  , KA_rate_beta  , sigs_size           , signal_time  , ...
             KA_rate_learned , AII            , Num_states_A  , Data.num_sub_sigs  );
         
             A_all  = sampling_rate_all( app,tau_A_res  , Num_states_A   , A_all_alpha    , A_all_base     , Data.num_sub_sigs );
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%    Sample the donor and ccceptor channel efficiencies     %%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        if Data.sample_eta
          [ eta_D_learned   , eta_A_learned       , Data.eta_accept    , log_like           ] = sampling_eta( ...
                 ... 
           app,Data.eta_accept , Data.num_sub_sigs   , IRF_D_str2         , IRF_A_str2         , ...
           F_res            , tau_D_res           , KA_val_learned     , Data.alpha_eta_D   , ...
           Data.beta_eta_D  , Data.eta_prop_D     , Data.eta_prop_A    , Data.alpha_eta_A   , ...
           Data.beta_eta_A  , eta_D_learned       , eta_A_learned      , pi_learned         , ...
           max_bin          , KD_val_learned      , KF_val_learned     , indx1              , ...
           indx2            , sigg1               , sigg2              , log_like           , ...
           tau_A_res        , IRFDDstr2           , IRFAAstr2          , sigg11             , ...
           sigg22          );
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%                     MAP estimate                      %%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        [ map                , Data.map_KF_rate   , Data.map_KD_rate   , Data.map_KA_rate   , ...
          Data.map_KF_val    , Data.map_KD_val    , Data.map_KA_val    , Data.map_F_res     , ...
          Data.map_tau_D     , Data.map_tau_A     , Data.map_eta_D     , Data.map_eta_A     , ...
          Data.map_mu_D_ex   , Data.map_mu_A_ex   , Data.map_mu_BD     , Data.map_mu_BA     , ...
          Data.map_F_all     , Data.map_D_all     , Data.map_A_all     , RAW_map            ] = MAP_estimate( ...
          ...
          app,map            , KF_rate_learned    , KD_rate_learned    , KA_rate_learned    , ...
          KF_val_learned     , KD_val_learned     , KA_val_learned     , eta_D_learned      , ...
          eta_A_learned      , mu_D_ex_learned    , mu_A_ex_learned    , mu_BD_learned      , ...
          mu_BA_learned      , F_res              , tau_D_res          , tau_A_res          , ...
          log_like           , Data.alpha_mu_D_ex , Data.beta_mu_D_ex  , Data.alpha_mu_A_ex , ...
          Data.beta_mu_A_ex  , Data.alpha_mu_BD   , Data.beta_mu_BD    , Data.alpha_mu_BA   , ...
          Data.beta_mu_BA    , Data.KD_rate_alpha , Data.KD_rate_beta  , Data.KF_rate_alpha , ...
          Data.KF_rate_beta  , KD_log_like        , KF_log_like        , Data.map_KF_rate   , ...
          Data.map_KD_rate   , Data.map_KA_rate   , Data.map_KF_val    , Data.map_KD_val    , ...
          Data.map_KA_val    , Data.map_F_res     , Data.map_tau_D     , Data.map_tau_A     , ...
          Data.map_eta_D     , Data.map_eta_A     , Data.map_mu_D_ex   , Data.map_mu_A_ex   , ...
          F_all_base         , D_all_base         , A_all_base         , Data.map_mu_BD     , ...
          Data.map_mu_BA     , Data.num_sub_sigs  , Data.alpha_eta_D   , Data.beta_eta_D    , ...
          Data.alpha_eta_A   , Data.beta_eta_A    , Data.KA_val_alpha  , Data.KA_val_beta   , ...
          Data.KF_val_alpha  , Data.KF_val_beta   , Data.KD_val_alpha  , Data.KD_val_beta   , ...
          Data.map_F_all     , Data.map_D_all     , Data.map_A_all     , F_all              , ...
          D_all              , A_all              , Num_states_F       , Num_states_D       , ...
          Num_states_A       , F_all_alpha        , D_all_alpha        , A_all_alpha        , ...
          KA_log_like        , Data.KA_rate_alpha , Data.KA_rate_beta );
           
          Data.map     = [Data.map     , map     ];
          Data.RAW_map = [Data.RAW_map , RAW_map ];
          
          
      if (floor(i/Data.save_size)*Data.save_size-i)==0
          app.Gauge.Value = 100*(i./Max_iter);
          drawnow
          for mmk=1:Data.num_sub_sigs
              Data.F_res{mmk}           = [ Data.F_res{mmk}           ; F_res{mmk}             ];
              Data.tau_D_res{mmk}       = [ Data.tau_D_res{mmk}       ; tau_D_res{mmk}         ];
              Data.tau_A_res{mmk}       = [ Data.tau_A_res{mmk}       ; tau_A_res{mmk}         ];
              Data.KF_val_learned{mmk}  = [ Data.KF_val_learned{mmk}  ; KF_val_learned(mmk,:)  ];
              Data.KD_val_learned{mmk}  = [ Data.KD_val_learned{mmk}  ; KD_val_learned(mmk,:)  ];
              Data.KA_val_learned{mmk}  = [ Data.KA_val_learned{mmk}  ; KA_val_learned(mmk,:)  ];
              Data.mu_D_ex_learned{mmk} = [ Data.mu_D_ex_learned{mmk} ; mu_D_ex_learned{mmk}   ];
              Data.mu_A_ex_learned{mmk} = [ Data.mu_A_ex_learned{mmk} ; mu_A_ex_learned{mmk}   ];
          end 
          Data.KF_rate_learned  = cat(3,Data.KF_rate_learned , KF_rate_learned );
          Data.KD_rate_learned  = cat(3,Data.KD_rate_learned , KD_rate_learned );
          Data.KA_rate_learned  = cat(3,Data.KA_rate_learned , KA_rate_learned );
          Data.F_all             = [ Data.F_all            , F_all            ];
          Data.D_all             = [ Data.D_all            , D_all            ];
          Data.A_all             = [ Data.A_all            , A_all            ];
          if Data.sample_eta
             Data.eta_A_learned  = [ Data.eta_A_learned    , eta_A_learned    ];
             Data.eta_D_learned  = [ Data.eta_D_learned    , eta_D_learned    ];
          end
          Data.mu_BD_learned     = [ Data.mu_BD_learned    , mu_BD_learned    ];
          Data.mu_BA_learned     = [ Data.mu_BA_learned    , mu_BA_learned    ];
          Data.pi_accept         = pi_accept;


          if (floor(i/(Data.save_size*10))*10*Data.save_size-i)==0
          if  Data.save_on_off
               % Save the data in .mat format
               save(app.FilenameLabel.Text(13:end),'Data','-v7.3')

               cla(app.UIAxes_3)
               cla(app.UIAxes_4)
               cla(app.UIAxes_5)
               cla(app.UIAxes_6)
               cla(app.UIAxes_7)
               cla(app.UIAxes_8)

               burn_in   = max([1 floor((app.BurninEditField.Value/100)*length(Data.F_all(1,:)))]);

               sigg_n2_F = []; max_F(1) = 0;
               for mme=1:Data.num_sub_sigs
                   if  mme==1
                       sigg_n2_F =Data.signals{mme}(2,:); max_F(2) = max(unique(sigg_n2_F));
                   else
                       sigg_n2_F =[sigg_n2_F, max_F(mme)+Data.signals{mme}(2,:)]; max_F(mme+1) = max(sigg_n2_F);
                   end
               end

               % Plot F trajectory
               axInnerPos2 = app.UIAxes_3.InnerPosition; 
               if  isfield(Data,'F_fluc_real')
                   stairs(app.UIAxes_3,Data.signals{1}(2,:),Data.F_fluc_real,'g')
               end
               ress=[]; F_Ind_p=[]; map_F=[]; hold(app.UIAxes_3,'on')
               for mmk=1:Data.num_sub_sigs
                   F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
               for ii=1:size(Data.F_all(1,burn_in:end),2)
                   ttf(:,ii)=Data.KF_val_learned{mmk}(burn_in+ii-1,Data.F_res{mmk}(burn_in+ii-1,:));
               end
                   ress            = [ress,ttf']; map_F     = [map_F,Data.map_KF_val(mmk,Data.map_F_res{mmk})];
               end
               F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

               hr11=stairs(app.UIAxes_3,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
               hr22=fill(app.UIAxes_3,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
               hr33=stairs(app.UIAxes_3,F_Ind_p,map_F,'-.','Color','m');
               if  isfield(Data,'F_fluc_real')
                   F_maxx = max([1,max([Data.F_fluc_real,max(F_m_up1)])*1.1]);
               else
                   F_maxx = max([1,max(F_m_up1)*1.1]);
               end
               ylim(app.UIAxes_3,[0 F_maxx]);   xlim(app.UIAxes_3,[0 max(Data.signals{1}(2,:))]);
               app.UIAxes_3.XTickLabel=[];box(app.UIAxes_3,'off');hold(app.UIAxes_3,'off')
               innerPosDiff3         = axInnerPos2 - app.UIAxes_3.InnerPosition;  
               app.UIAxes_3.Position = app.UIAxes_3.Position + innerPosDiff3;


               % Plot Donor lifetime trajectory
               axInnerPos3 = app.UIAxes_4.InnerPosition; 
               if  isfield(Data,'F_fluc_real')
                   stairs(app.UIAxes_4,Data.signals{1}(2,:),Data.tau_D_fluc_real,'g')
               end
               ress=[]; F_Ind_p=[]; map_F=[]; hold(app.UIAxes_4,'on')
               for mmk=1:Data.num_sub_sigs
                   F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
               for ii=1:size(Data.D_all(1,burn_in:end),2)
                   ttf(:,ii)=Data.KD_val_learned{mmk}(burn_in+ii-1,Data.tau_D_res{mmk}(burn_in+ii-1,:));
               end
                   ress            = [ress,ttf']; map_F     = [map_F,Data.map_KD_val(mmk,Data.map_tau_D{mmk})];
               end
               F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

               stairs(app.UIAxes_4,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
               fill(app.UIAxes_4,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
               stairs(app.UIAxes_4,F_Ind_p,map_F,'-.','Color','m');
               if  isfield(Data,'F_fluc_real')
                   D_maxx=  max([1,max(Data.tau_D_fluc_real)*1.1,max(F_m_up1)*1.1]);
               else
                   D_maxx=  max([1,max(F_m_up1)*1.1]);
               end
               ylim(app.UIAxes_4,[0 D_maxx])
               xlim(app.UIAxes_4,[0 max(Data.signals{1}(2,:))]);
               app.UIAxes_4.XTickLabel=[];box(app.UIAxes_4,'off');hold(app.UIAxes_4,'off')
               innerPosDiff4        = axInnerPos3 - app.UIAxes_4.InnerPosition;  
               app.UIAxes_4.Position = app.UIAxes_4.Position + innerPosDiff4;


               % Plot Acceptor lifetime trajectory
               axInnerPos4 = app.UIAxes_5.InnerPosition; 
               if  isfield(Data,'F_fluc_real')
                   stairs(app.UIAxes_5,Data.signals{1}(2,:),Data.tau_A_fluc_real,'g')
               end
               ress=[]; F_Ind_p=[]; map_F=[]; hold(app.UIAxes_5,'on')
               for mmk=1:Data.num_sub_sigs
                   F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
               for ii=1:size(Data.A_all(1,burn_in:end),2)
                   ttf(:,ii)=Data.KA_val_learned{mmk}(burn_in+ii-1,Data.tau_A_res{mmk}(burn_in+ii-1,:));
               end
                   ress            = [ress,ttf']; map_F     = [map_F,Data.map_KA_val(mmk,Data.map_tau_A{mmk})];
               end
               F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

               stairs(app.UIAxes_5,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
               fill(app.UIAxes_5,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
               stairs(app.UIAxes_5,F_Ind_p,map_F,'-.','Color','m');
               if  isfield(Data,'F_fluc_real')
                   A_maxx=max([1,max(Data.tau_A_fluc_real)*1.1,max(F_m_up1)*1.1]);
               else
                   A_maxx=max([1,max(F_m_up1)*1.1]);
               end
               ylim(app.UIAxes_5,[0 A_maxx]);  xlim(app.UIAxes_5,[0 max(Data.signals{1}(2,:))]);
               box(app.UIAxes_5,'off'); hold(app.UIAxes_5,'off')
               innerPosDiff5         = axInnerPos4 - app.UIAxes_5.InnerPosition;  
               app.UIAxes_5.Position = app.UIAxes_5.Position + innerPosDiff5;


% Plot the FRET transition rates
if  Data.Num_states_F>1
    edgehF=linspace(0,max(max(max(Data.KF_rate_learned(:,:,burn_in:end)))),50);
    SS_F_max=0;
    for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if  m~=n
                SS=histcounts(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),edgehF,'Normalization','pdf');
                if  max(SS)>SS_F_max
                    SS_F_max = max(SS);
                end
            end
        end
    end
    if  isfield(Data,'F_fluc_real')
        for m=1:Data.Num_states_F
            for n=1:Data.Num_states_F
                if m~=n
                   line(app.UIAxes_8,[1 1]*Data.KF_real(m,n),[0 1]*SS_F_max*1.1,'LineStyle','-.','Color','g')
                   hold(app.UIAxes_8,'on')
                end
            end
        end
    end
    for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if  m~=n
                line(app.UIAxes_8,[1 1]*Data.map_KF_rate(m,n),[0 1]*SS_F_max.*1.1,'LineStyle','-.','Color','b')
                hold(app.UIAxes_8,'on')
                xx1=[quantile(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
                     quantile(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
                fill(app.UIAxes_8,[xx1,fliplr(xx1)],[0 0 1 1]*SS_F_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
                histogram(app.UIAxes_8,permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),edgehF,'Normalization','pdf');
            end
        end
    end
    xlim(app.UIAxes_8,[0,max(max(max(Data.KF_rate_learned(:,:,burn_in:end))))])
    hold(app.UIAxes_8,'off');box(app.UIAxes_8,'off')
end


% Plot the Acceptor_PIFE transition rates
if  Data.Num_states_A>1
    edgehA=linspace(0,max(max(max(Data.KA_rate_learned(:,:,burn_in:end)))),50);
    SS_A_max=0;
    for  m=1:Data.Num_states_A
         for n=1:Data.Num_states_A
             if  m~=n
                 SS=histcounts(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),edgehA,'Normalization','pdf');
                 if  max(SS)>SS_A_max
                     SS_A_max = max(SS);
                 end
             end
         end
    end
    if  isfield(Data,'F_fluc_real')
        for  m=1:Data.Num_states_A
             for  n=1:Data.Num_states_A
                  if m~=n
                     line(app.UIAxes_7,[1 1]*Data.KA_real(m,n),[0 1]*SS_A_max*1.1,'LineStyle','-.','Color','g')
                     hold(app.UIAxes_7,'on')
                  end
             end
        end
    end
    for  m=1:Data.Num_states_A
         for  n=1:Data.Num_states_A
              if  m~=n
                  line(app.UIAxes_7,[1 1]*Data.map_KA_rate(m,n),[0 1]*SS_A_max.*1.1,'LineStyle','-.','Color','b')
                  hold(app.UIAxes_7,'on')
                  xx1=[quantile(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
                       quantile(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
                  fill(app.UIAxes_7,[xx1,fliplr(xx1)],[0 0 1 1]*SS_A_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
                  histogram(app.UIAxes_7,permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),edgehA,'Normalization','pdf');
              end
         end
    end
    xlim(app.UIAxes_7,[0,max(max(max(Data.KA_rate_learned(:,:,burn_in:end))))])
    hold(app.UIAxes_7,'off'); box(app.UIAxes_7,'off')
end


% Plot the Donor_PIFE transition rates
if  Data.Num_states_D>1
    edgehP=linspace(0,max(max(max(Data.KD_rate_learned(:,:,burn_in:end)))),50);
    SS_P_max=0;
    for  m=1:Data.Num_states_D
         for  n=1:Data.Num_states_D
              if  m~=n
                  SS=histcounts(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),edgehP,'Normalization','pdf');
                  if  max(SS)>SS_P_max
                      SS_P_max = max(SS);
                  end
              end
         end
    end
    if  isfield(Data,'F_fluc_real')
        for  m=1:Data.Num_states_D
             for  n=1:Data.Num_states_D
                  if  m~=n
                      line(app.UIAxes_6,[1 1]*Data.KD_real(m,n),[0 1]*SS_P_max*1.1,'LineStyle','-.','Color','g')
                      hold(app.UIAxes_6,'on')
                  end
             end
        end
    end
    for  m=1:Data.Num_states_D
         for  n=1:Data.Num_states_D
              if  m~=n
                  line(app.UIAxes_6,[1 1]*Data.map_KD_rate(m,n),[0 1]*SS_P_max.*1.1,'LineStyle','-.','Color','b')
                  hold(app.UIAxes_6,'on')
                  xx1=[quantile(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
                       quantile(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
                  fill(app.UIAxes_6,[xx1,fliplr(xx1)],[0 0 1 1]*SS_P_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
                  histogram(app.UIAxes_6,permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),edgehP,'Normalization','pdf');
              end
         end
    end
    xlim(app.UIAxes_6,[0,max(max(max(Data.KD_rate_learned(:,:,burn_in:end))))])
    hold(app.UIAxes_6,'off'); box(app.UIAxes_6,'off')
end


          end
          end
           
          tester = false;
      else
          tester =true;
      end
          
      
   end
  
   if tester
       for mmk=1:Data.num_sub_sigs
           Data.F_res{mmk}           = [ Data.F_res{mmk}           ; F_res{mmk}             ];
           Data.tau_D_res{mmk}       = [ Data.tau_D_res{mmk}       ; tau_D_res{mmk}         ];
           Data.tau_A_res{mmk}       = [ Data.tau_A_res{mmk}       ; tau_A_res{mmk}         ];
           Data.KF_val_learned{mmk}  = [ Data.KF_val_learned{mmk}  ; KF_val_learned(mmk,:)  ];
           Data.KD_val_learned{mmk}  = [ Data.KD_val_learned{mmk}  ; KD_val_learned(mmk,:)  ];
           Data.KA_val_learned{mmk}  = [ Data.KA_val_learned{mmk}  ; KA_val_learned(mmk,:)  ];
           Data.mu_D_ex_learned{mmk} = [ Data.mu_D_ex_learned{mmk} ; mu_D_ex_learned{mmk}   ];
           Data.mu_A_ex_learned{mmk} = [ Data.mu_A_ex_learned{mmk} ; mu_A_ex_learned{mmk}   ];
       end
       Data.KF_rate_learned   = cat(3,Data.KF_rate_learned , KF_rate_learned );
       Data.KD_rate_learned   = cat(3,Data.KD_rate_learned , KD_rate_learned );
       Data.KA_rate_learned   = cat(3,Data.KA_rate_learned , KA_rate_learned );
       Data.F_all             = [ Data.F_all               , F_all           ];
       Data.D_all             = [ Data.D_all               , D_all           ];
       Data.A_all             = [ Data.A_all               , A_all           ];
       if Data.sample_eta
          Data.eta_A_learned  = [ Data.eta_A_learned       , eta_A_learned   ];
          Data.eta_D_learned  = [ Data.eta_D_learned       , eta_D_learned   ];
       end
       Data.mu_BD_learned     = [ Data.mu_BD_learned       , mu_BD_learned   ];
       Data.mu_BA_learned     = [ Data.mu_BA_learned       , mu_BA_learned   ];
       Data.pi_accept         = pi_accept  ;
%        Data.tau_accept        = tau_accept ;
   end

   if  Data.save_on_off
       % Save the data in .mat format
       save(app.FilenameLabel.Text(13:end),'Data','-v7.3')
   end
end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%% Pre-calculation and preparing the parameter to be used inside the Gibbs Sampler
%
%  This code is written by Sina Jazani (09/14/2021)
%  Contact: sjazani1@jhu.edu

function[tester            , map              , KF_rate_learned  , KD_rate_learned   , ...
         IRF_D_str2        , IRF_A_str2       , eta_A_learned    , signal_time       , ...
         sigs_size         , eta_D_learned    , mu_D_ex_learned  , mu_A_ex_learned   , ...
         mu_BD_learned     , mu_BA_learned    , pi_accept        , prop_mu_D_ex      , ...
         alpha_mu_D_ex     , beta_mu_D_ex     , prop_mu_A_ex     , alpha_mu_A_ex     , ...
         beta_mu_A_ex      , max_bin          , prop_mu_BD       , alpha_mu_BD       , ...
         beta_mu_BD        , prop_mu_BA       , alpha_mu_BA      , beta_mu_BA        , ...
         KF_val_prop       , KF_val_alpha     , KF_val_beta      , AII               , ...
         A_all             , sigg11           , KD_val_prop      , KD_val_alpha      , ...
         KD_val_beta       , KD_val_learned   , KF_val_learned   , sigg22            , ...
         FII               , DII              , F_res            , tau_D_res         , ...
         indx1             , indx2            , sigg1            , sigg2             , ...
         pi_learned        , KF_rate_alpha    , KF_rate_beta     , Num_states_F      , ...
         KD_rate_alpha     , KD_rate_beta     , Num_states_D     , F_all             , ...
         D_all             , F_all_alpha      , F_all_base       , D_all_alpha       , ...
         D_all_base        , Num_states_A     , KA_rate_alpha    , KA_rate_beta      , ...
         KA_val_prop       , KA_val_alpha     , KA_val_beta      , KA_rate_learned   , ...
         A_all_alpha       , A_all_base       , tau_A_res        , KA_val_learned    , ...
         IRFDDstr2         , IRFAAstr2        ] = Initiation_Gibbs( app , Data )


   tester            = false;
   
   Num_states_F      = Data.Num_states_F ;
   Num_states_D      = Data.Num_states_D ;
   Num_states_A      = Data.Num_states_A ;
   
   KF_rate_alpha     = Data.KF_rate_alpha ;
   KF_rate_beta      = Data.KF_rate_beta;
   KD_rate_alpha     = Data.KD_rate_alpha ;
   KD_rate_beta      = Data.KD_rate_beta;
   KA_rate_alpha     = Data.KA_rate_alpha ;
   KA_rate_beta      = Data.KA_rate_beta;
   
   map               = Data.map(end);

   IRF_D_str         = Data.IRF_str(1);
   IRF_A_str         = Data.IRF_str(2);
   
   IRF_D_str_sqrt2   = IRF_D_str*sqrt(2) ;
   IRF_D_str2        = (IRF_D_str^2)       ;
   IRF_A_str_sqrt2   = IRF_A_str*sqrt(2) ;
   IRF_A_str2        = (IRF_A_str^2)       ;
   
   IRFDDstr2         = IRF_D_str./sqrt(2);
   IRFAAstr2         = IRF_A_str./sqrt(2);

   eta_A_learned     = Data.eta_A_learned(end);
   eta_D_learned     = Data.eta_D_learned(end);
   
   mu_BD_learned     = Data.mu_BD_learned(:,end);
   mu_BA_learned     = Data.mu_BA_learned(:,end);
   
   pi_accept         = Data.pi_accept           ;
   prop_mu_D_ex      = Data.prop_mu_D_ex          ;
   alpha_mu_D_ex     = Data.alpha_mu_D_ex         ; 
   beta_mu_D_ex      = Data.beta_mu_D_ex          ;
   
   prop_mu_A_ex      = Data.prop_mu_A_ex       ;
   alpha_mu_A_ex     = Data.alpha_mu_A_ex      ;
   beta_mu_A_ex      = Data.beta_mu_A_ex       ;
   
   prop_mu_BD        = Data.prop_mu_BD          ;
   alpha_mu_BD       = Data.alpha_mu_BD         ;
   beta_mu_BD        = Data.beta_mu_BD          ;
   
   prop_mu_BA        = Data.prop_mu_BA          ;
   alpha_mu_BA       = Data.alpha_mu_BA         ;
   beta_mu_BA        = Data.beta_mu_BA          ; 
   
   KF_val_prop       = Data.KF_val_prop         ;
   KF_val_alpha      = Data.KF_val_alpha        ;
   KF_val_beta       = Data.KF_val_beta         ;
   KD_val_prop       = Data.KD_val_prop         ;
   KD_val_alpha      = Data.KD_val_alpha        ;
   KD_val_beta       = Data.KD_val_beta         ;
   KA_val_prop       = Data.KA_val_prop         ;
   KA_val_alpha      = Data.KA_val_alpha        ;
   KA_val_beta       = Data.KA_val_beta         ;

if isfield(Data,'max_bin')
    if  Data.max_bin<Data.pulse_priod 
        max_bin      = Data.max_bin./2          ;
    else
        max_bin      = Data.pulse_priod./2      ;
    end
else
    max_bin          = Data.pulse_priod./2      ;
end


   signal_time           = [];
   indx1                 = [];
   indx2                 = [];
   pi_learned            = []; 

   KD_rate_learned       = [];
   KF_rate_learned       = [];
   KA_rate_learned       = [];
   
   KD_rate_learned(:,:,1)  = Data.KD_rate_learned(:,:,end);
   KF_rate_learned(:,:,1)  = Data.KF_rate_learned(:,:,end);
   KA_rate_learned(:,:,1)  = Data.KA_rate_learned(:,:,end);
   
   
   F_all                 = Data.F_all(:,end) ;
   D_all                 = Data.D_all(:,end) ;
   A_all                 = Data.A_all(:,end) ;


   F_all_alpha           = Data.F_all_alpha  ;
   F_all_base            = Data.F_all_base ;

   D_all_alpha           = Data.D_all_alpha  ;
   D_all_base            = Data.D_all_base ;
   
   A_all_alpha           = Data.A_all_alpha  ;
   A_all_base            = Data.A_all_base ;
   
   for  mmk=1:Data.num_sub_sigs  
       
        mu_D_ex_learned{mmk}   = Data.mu_D_ex_learned{mmk}(end,:);
        mu_A_ex_learned{mmk}   = Data.mu_A_ex_learned{mmk}(end,:);
     
        KD_val_learned(mmk,:)   = Data.KD_val_learned{mmk}(end,:);
        KF_val_learned(mmk,:)   = Data.KF_val_learned{mmk}(end,:);
        KA_val_learned(mmk,:)   = Data.KA_val_learned{mmk}(end,:);
   

        TF                     = Data.KF_rate_learned(:,:,end)-diag(sum(Data.KF_rate_learned(:,:,end),1));
        TD                     = Data.KD_rate_learned(:,:,end)-diag(sum(Data.KD_rate_learned(:,:,end),1));
        TA                     = Data.KA_rate_learned(:,:,end)-diag(sum(Data.KA_rate_learned(:,:,end),1));
        
        sigs_size(mmk,1)       = Data.sigs_size(mmk)           ;
        signal_time{mmk}       = diff(Data.signals{mmk}(2,:) ) ;
        

        [vecF , valF]          = eig(TF) ; vallF = diag(valF); vec1F = vecF^-1;
        [vecD , valD]          = eig(TD) ; vallD = diag(valD); vec1D = vecD^-1;
        [vecA , valA]          = eig(TA) ; vallA = diag(valA); vec1A = vecA^-1;

        for n = 1:sigs_size(mmk)-1
            FII{mmk}(:,:,n)    = real(vecF*diag(exp(vallF*signal_time{mmk}(n)))*(vec1F));
            DII{mmk}(:,:,n)    = real(vecD*diag(exp(vallD*signal_time{mmk}(n)))*(vec1D));
            AII{mmk}(:,:,n)    = real(vecA*diag(exp(vallA*signal_time{mmk}(n)))*(vec1A));
        end
           
        F_res{mmk}             = Data.F_res{mmk}(end,:);
        tau_D_res{mmk}         = Data.tau_D_res{mmk}(end,:);
        tau_A_res{mmk}         = Data.tau_A_res{mmk}(end,:);
      
        
        indx1{mmk}             = find(Data.signals{mmk}(3,:)==1); % index of Donor channel detected photons
        indx2{mmk}             = find(Data.signals{mmk}(3,:)==2); % index of Acceptor channel detected  photons
        
        sigg1{mmk}             = Data.IRF_mean(1)-Data.signals{mmk}(1,indx1{mmk});
        sigg2{mmk}             = Data.IRF_mean(2)-Data.signals{mmk}(1,indx2{mmk});
        
        sigg11{mmk}            = sigg1{mmk}./IRF_D_str_sqrt2;
        sigg22{mmk}            = sigg2{mmk}./IRF_A_str_sqrt2;


        A1                     = exp(-mu_D_ex_learned{mmk}(end,tau_D_res{mmk}));
        A2                     = exp(-mu_A_ex_learned{mmk}(end,tau_A_res{mmk}));
        A3                     = exp(-mu_BD_learned(mmk,1));
        A4                     = exp(-mu_BA_learned(mmk,1));
        
        B                      = [(1-A1).*A2.*A3.*A4 ; ...
                                  (1-A2).*A1.*A3.*A4 ; ...
                                  (1-A3).*A1.*A2.*A4 ; ...
                                  (1-A4).*A1.*A2.*A3 ];

        pi_learned{mmk}        = (B./sum(B,1));
    end     

end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%      Maximum posteriori calculation       %%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% Calculate the Maximum of posteriori (MAP) and corresponding values of the parameters of interests
%
%  This code is written by Sina Jazani (09/14/2021)
%  Contact: sjazani1@jhu.edu
function[ map             , map_KF_rate       , map_KD_rate       , map_KA_rate      , map_KF_val      , map_KD_val        , map_KA_val       , ...
          map_F_res       , map_tau_D_res     , map_tau_A_res     , map_eta_D        , map_eta_A       , map_mu_D_ex       , map_mu_A_ex      , ...
          map_mu_BD       , map_mu_BA         , map_F_all         , map_D_all        , map_A_all       ,  RAW_map           ] = MAP_estimate( ...
          ...
          app,map         , KF_rate_learned   , KD_rate_learned   , KA_rate_learned  , KF_val_learned  , KD_val_learned    , KA_val_learned   , ...
          eta_D_learned   , eta_A_learned     , mu_D_ex_learned   , mu_A_ex_learned  , mu_BD_learned   , mu_BA_learned     , F_res            , ...
          tau_D_res       , tau_A_res         , log_like          , alpha_mu_D_ex    , beta_mu_D_ex    , alpha_mu_A_ex     , beta_mu_A_ex     , ...
          alpha_mu_BD     , beta_mu_BD        , alpha_mu_BA       , beta_mu_BA       , ...
          KD_rate_alpha   , KD_rate_beta      , KF_rate_alpha     , KF_rate_beta     , KD_log_like     , KF_log_like       , map_KF_rate      , ...
          map_KD_rate     , map_KA_rate       , map_KF_val        , map_KD_val       , map_KA_val      , map_F_res         , map_tau_D_res    , ...
          map_tau_A_res   , map_eta_D         , map_eta_A         , map_mu_D_ex      , map_mu_A_ex     , F_all_base        , D_all_base       , ...
          A_all_base      , map_mu_BD         , map_mu_BA         , num_sub_sigs     , alpha_eta_D     , beta_eta_D        , alpha_eta_A      , ...
          beta_eta_A      , KA_val_alpha      , KA_val_beta       , KF_val_alpha     , KF_val_beta     , KD_val_alpha      , KD_val_beta      , ...
          map_F_all       , map_D_all         , map_A_all         , F_all            , D_all           , A_all             , Num_states_F     , ...
          Num_states_D    , Num_states_A      , F_all_alpha       , D_all_alpha      , A_all_alpha     , KA_log_like        , ...
          KA_rate_alpha   , KA_rate_beta      )

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% INPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % sigs_size             :  The length of the trajectory, equivalent to number of detetcted photons
   % IRF_D_str_sqrt2       :  A pre-calculated value for the likelihood. (sqrt(2*IRF_D_str^2))
   % IRF_D_str2            :  A pre-calculated value for the likelihood. IRF_D_str^2
   % IRF_A_str_sqrt2       :  A pre-calculated value for the likelihood. (sqrt(2*IRF_A_str^2))
   % IRF_A_str2            :  A pre-calculated value for the likelihood. IRF_A_str^2
   % tau_A                 :  Acceptor lifetimes for any detected photon
   % tau_D_res             :  Inverse of donor lifetimes for any detected photon
   % F_res                 :  FRET rates for each state
   % eta_D                 :  Cross-talk ratio from donor channel
   % eta_A                 :  Cross-talk ratio from acceptor channel
   % PI                    :  Weights on the source of photon detections (Donor , Acceptor , Donor backgound , Acceptor background)
   % T_pulse               :  Inter-pulse time
   % indx1                 :  Index over photons collected in the donor channel
   % indx2                 :  Index over photons collected in the acceptor channel
   % sigg1                 :  Pre-calculated values for the likelihood
   % sigg2                 :  Pre-calculated values for the likelihood
   % Num_states            :  Nember of FRET states
   % KF_rate_learned       :  Estimated FRET state transition rate matrix
   % KD_rate_learned       :  Estimated donor-PIFE state transition rate matrix
   % KA_rate_learned       :  Estimated acceptor-PIFE state transition rate matrix
   % KF_val_learned        :  Estimated donor lifetime for each FRET state
   % KD_val_learned        :  Estimated donor lifetime for each donor-PIFE state
   % KA_val_learned        :  Estimated donor lifetime for each acceptor-PIFE state
   % F_res                 :  Estimated FRET state trajectory
   % tau_D_res             :  Estimated donor-PIFE state trajectory
   % tau_A_res             :  Estimated acceptor-PIFE state trajectory
   % eta_D_learned         :  Estimated cross-talk ratio from donor channel  
   % eta_A_learned         :  Estimated cross-talk ratio from acceptor channel 
   % mu_D_ex_learned       :  Estimated excitation rates for each donor-PIFE states
   % mu_A_ex_learned       :  Estimated excitation rates for each acceptor-PIFE states
   % mu_BD_learned         :  Estimated background photon emission rate collected by donor channel 
   % mu_BA_learned         :  Estimated background photon emission rate collected by acceptor channel 
   % F_all                 :  Estimated weights on FRET rate states
   % D_all                 :  Estimated weights on Donor-PIFE states
   % A_all                 :  Estimated weights on acceptor-PIFE states

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% OUTPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % map                   :  Value of the MAP estimate
   % RAW_map               :  The likelihood for any detected photon
   % map_KF_rate           :  Maximum of Posteriori of FRET state transition rate matrix
   % map_KD_rate           :  Maximum of Posteriori of donor-PIFE state transition rate matrix
   % map_KA_rate           :  Maximum of Posteriori of acceptor-PIFE state transition rate matrix
   % map_KF_val            :  Maximum of Posteriori of donor lifetime for each FRET state
   % map_KD_val            :  Maximum of Posteriori of donor lifetime for each donor-PIFE state
   % map_KA_val            :  Maximum of Posteriori of donor lifetime for each acceptor-PIFE state
   % map_F_res             :  Maximum of Posteriori of FRET state trajectory
   % map_tau_D_res         :  Maximum of Posteriori of donor-PIFE state trajectory
   % map_tau_A_res         :  Maximum of Posteriori of acceptor-PIFE state trajectory
   % map_eta_D             :  Maximum of Posteriori of cross-talk ratio from donor channel  
   % map_eta_A             :  Maximum of Posteriori of cross-talk ratio from acceptor channel 
   % map_mu_D_ex           :  Maximum of Posteriori of excitation rates for each donor-PIFE states
   % map_mu_A_ex           :  Maximum of Posteriori of excitation rates for each acceptor-PIFE states
   % map_mu_BD             :  Maximum of Posteriori of background photon emission rate collected by donor channel 
   % map_mu_BA             :  Maximum of Posteriori of background photon emission rate collected by acceptor channel 
   % map_F_all             :  Maximum of Posteriori of weights on FRET rate states
   % map_D_all             :  Maximum of Posteriori of weights on Donor-PIFE states
   % map_A_all             :  Maximum of Posteriori of weights on acceptor-PIFE states
   
      
% Calculating the joint posteior probability basssed on the sampled values of parameters of interest
map_new=0;ssA=zeros(Num_states_A,1);ssP=zeros(Num_states_D,1);ssF=zeros(Num_states_F,1);


% Prior on excitation rates for all sub traces
for mmk=1:num_sub_sigs
    map_new =map_new + sum(-alpha_mu_D_ex*log(beta_mu_D_ex)+gammaln(alpha_mu_D_ex) +(alpha_mu_D_ex-1)*log(mu_D_ex_learned{mmk})-mu_D_ex_learned{mmk}/beta_mu_D_ex);
    map_new =map_new + sum(-alpha_mu_A_ex*log(beta_mu_A_ex)+gammaln(alpha_mu_A_ex) +(alpha_mu_A_ex-1)*log(mu_A_ex_learned{mmk})-mu_A_ex_learned{mmk}/beta_mu_A_ex);

    ssA = ssA + histcounts(tau_A_res{mmk}(1),1:Num_states_A+1)';
    ssP = ssP + histcounts(tau_D_res{mmk}(1),1:Num_states_D+1)';
    ssF = ssF + histcounts(F_res{mmk}(1),1:Num_states_F+1)';
end

% Prior on beta_{hyper,D}
map_new = map_new+gammaln(sum(D_all_alpha*D_all_base+ssP))+sum((D_all_alpha*D_all_base+ssP-1).*log(D_all+eps)-gammaln(D_all_alpha*D_all_base+ssP+eps));

% Prior on beta_{hyper,F}
map_new = map_new+gammaln(sum(F_all_alpha*F_all_base+ssF))+sum((F_all_alpha*F_all_base+ssF-1).*log(F_all+eps)-gammaln(F_all_alpha*F_all_base+ssF+eps)); 

% Prior on beta_{hyper,A}
map_new = map_new+gammaln(sum(A_all_alpha*A_all_base+ssA))+sum((A_all_alpha*A_all_base+ssA-1).*log(A_all+eps)-gammaln(A_all_alpha*A_all_base+ssA+eps)); 


map_new =map_new+ sum(log_like) + KD_log_like  + KF_log_like + KA_log_like+...
      sum( -KF_rate_alpha*log(KF_rate_beta) +gammaln(KF_rate_alpha) +(KF_rate_alpha-1)*log(KF_rate_learned)-KF_rate_learned/KF_rate_beta,'all','omitnan')+...
      sum( -KD_rate_alpha*log(KD_rate_beta) +gammaln(KD_rate_alpha) +(KD_rate_alpha-1)*log(KD_rate_learned)-KD_rate_learned/KD_rate_beta,'all','omitnan')+...
      sum( -KA_rate_alpha*log(KA_rate_beta) +gammaln(KA_rate_alpha) +(KA_rate_alpha-1)*log(KA_rate_learned)-KA_rate_learned/KA_rate_beta,'all','omitnan')+...
      +sum(-KF_val_alpha*log(KF_val_beta) +gammaln(KF_val_alpha) +(KF_val_alpha-1)*log(KF_val_learned)-KF_val_learned/KF_val_beta,'all')+...
      +sum(-KD_val_alpha*log(KD_val_beta) +gammaln(KD_val_alpha) +(KD_val_alpha-1)*log(KD_val_learned)-KD_val_learned/KD_val_beta,'all')...
      +sum(-KA_val_alpha*log(KA_val_beta) +gammaln(KA_val_alpha) +(KA_val_alpha-1)*log(KA_val_learned)-KA_val_learned/KA_val_beta,'all')...
      +sum(-alpha_mu_BD*log(beta_mu_BD)+gammaln(alpha_mu_BD) +(alpha_mu_BD-1)*log(mu_BD_learned)-mu_BD_learned/beta_mu_BD...
           -alpha_mu_BA*log(beta_mu_BA)+gammaln(alpha_mu_BA) +(alpha_mu_BA-1)*log(mu_BA_learned)-mu_BA_learned/beta_mu_BA)...
      +gammaln(alpha_eta_D+beta_eta_D)-gammaln(alpha_eta_D)-gammaln(beta_eta_D)+(alpha_eta_D-1)*log(eta_D_learned)+(beta_eta_D-1)*log(1-eta_D_learned)...
      +gammaln(alpha_eta_A+beta_eta_A)-gammaln(alpha_eta_A)-gammaln(beta_eta_A)+(alpha_eta_A-1)*log(eta_A_learned)+(beta_eta_A-1)*log(1-eta_A_learned);
       

% In the case of numercal under fellow, model might return imaginary number, and we weill stop the algorithm for further inverstigation
if imag(map_new)>0
   msgbox("The MAP estimate output complex numbers!","Numerical error","error");
end
      
% Save the value of the joint posterior 
RAW_map = map_new;
      

% Update the MAP estimations based on the values of the joint posterior
if  map_new>map
    map             = map_new           ; map_KF_val      = KF_val_learned    ; map_KD_val      = KD_val_learned    ;
    map_KA_val      = KA_val_learned    ; map_F_res       = F_res             ; map_tau_D_res   = tau_D_res         ;
    map_tau_A_res   = tau_A_res         ; map_eta_D       = eta_D_learned     ; map_eta_A       = eta_A_learned     ;
    map_mu_D_ex     = mu_D_ex_learned   ; map_mu_A_ex     = mu_A_ex_learned   ; map_mu_BD       = mu_BD_learned     ;
    map_mu_BA       = mu_BA_learned     ; map_KF_rate     = KF_rate_learned   ; map_KD_rate     = KD_rate_learned   ;
    map_KA_rate     = KA_rate_learned   ; map_F_all       = F_all             ; map_D_all       = D_all             ;
    map_A_all       = A_all             ;
end

end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%  Sampling from a discrete distribution  %%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
function j = Discrete_sampler( app , p )
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% INPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
     % p                             :  An array of weights
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% OUTPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
     % j                             :  Sampled value
P = cumsum(p);
j = find( P(end)*rand(1) <= P, 1 );
end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%  Sampling from a dirichlet distribution %%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Sampling from a dirichlet distribution
% written by Sina Jazani (09/27/2021)  email: (sina Jazani) sjazani1@jh.edu
function x = dirichletRnd( app , a)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% INPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
     % a                             :  An array of weights
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% OUTPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
     % x                             :  Sampled value
     
% take a sample from a dirichlet distribution
p = length(a);
x = gamrnd(a,1,p,1);
x = x ./ sum(x);
end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%   Calculate the log of the likelihood     %%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% Pre-calculation of the log likelihood
%
%  This code is written by Sina Jazani (03/28/2023)
%  Contact: sjazani1@jhu.edu
function[ log_lik ]= log_Likelihood(...
    ...
app,F_res   , tau_D_res    , tau_A        , eta_D       , ...
eta_A       , IRF_D_str2   , IRF_A_str2   , PI          , ...
T_pulse     , Indx1        , Indx2        , sigg1       , ...
sigg2       , IRFDDstr2    , IRFAAstr2    , sigg11      , ...
sigg22      )


KT1     = tau_D_res(1,Indx1)+F_res(1,Indx1) ;
tau_A1  = tau_A(1,Indx1);

KT2     = tau_D_res(1,Indx2)+F_res(1,Indx2) ;
tau_A2  = tau_A(1,Indx2);


% Original calculation
% AA1     = exp(KT1.*(0.5*IRF_D_str2.*KT1+sigg1)).*erfc(IRFDDstr2*KT1+sigg11);
% AA2     = exp(KT2.*(0.5*IRF_A_str2.*KT2+sigg2)).*erfc(IRFAAstr2*KT2+sigg22);


% Approximation of log(erfc) for large values>27.2, it is 6 times slower
AA1     = exp(KT1.*(0.5*IRF_D_str2.*KT1+sigg1)+log_erfc(app,IRFDDstr2*KT1+sigg11));
AA2     = exp(KT2.*(0.5*IRF_A_str2.*KT2+sigg2)+log_erfc(app,IRFAAstr2*KT2+sigg22));

DD1     = exp((0.5*IRF_D_str2./tau_A1+sigg1)./tau_A1).*erfc(IRFDDstr2./tau_A1+sigg11);
DD2     = exp((0.5*IRF_A_str2./tau_A2+sigg2)./tau_A2).*erfc(IRFAAstr2./tau_A2+sigg22);


log_lik = sum(log( (((PI(1,Indx1).*AA1.*tau_D_res(1,Indx1))+(PI(3,Indx1)/T_pulse)).*(1-eta_D))+...
                   (((PI(1,Indx1).*F_res(1,Indx1).*(DD1-AA1)./(KT1.*tau_A1-1))+(PI(2,Indx1).*DD1./tau_A1)+(PI(4,Indx1)/T_pulse)).*eta_A) +eps) )+...
...
          sum(log( (((PI(1,Indx2).*AA2.*tau_D_res(1,Indx2))+(PI(3,Indx2)/T_pulse)).*eta_D)+...
                   (((PI(1,Indx2).*F_res(1,Indx2).*(DD2-AA2)./(KT2.*tau_A2-1))+(PI(2,Indx2).*DD2./tau_A2)+(PI(4,Indx2)/T_pulse)).*(1-eta_A)) +eps) );

end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%% Direct sampling of the transition rate ratio to other states
%
%  This code is written by Sina Jazani (03/28/2023)
%  Contact: sjazani1@jhu.edu
function[ FDA_all ] = sampling_rate_all( app,FDA_res      , Num_states     , all_alpha    , all_base      ,num_sub_sigs )
      
ss=zeros(Num_states,1);
for ii=1:num_sub_sigs
    ss = ss + histcounts(FDA_res{ii}(1),1:Num_states+1)';
end

FDA_all = dirichletRnd(app,ss+all_alpha*all_base);

end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%% Sampling eta using Metropolis Hastings algorithm
%
%  This code is written by Sina Jazani (03/28/2023)
%  Contact: sjazani1@jhu.edu
function[ eta_D_learned   , eta_A_learned    , eta_accept , log_like ] = sampling_eta( ...
... 
app,eta_accept  , num_sub_sigs    , IRF_D_str2      , IRF_A_str2     , ...
F_res           , tau_fluc_D      , KA_val          , alpha_eta_D    , ...
beta_eta_D      , eta_prop_D      , eta_prop_A      , alpha_eta_A    , ...
beta_eta_A      , eta_D_learned   , eta_A_learned   , PI             , ...
T_pulse         , KD_val          , KF_val          , indx1          , ...
indx2           , sigg1           , sigg2           , log_like       , ...
tau_fluc_A      , IRFDDstr2       , IRFAAstr2       , sigg11         , ...
sigg22           )


for yu=1:5
    % Proposal values of the lifetimes
    bet_D = eta_prop_D*(1./eta_D_learned-1);
    bet_A = eta_prop_A*(1./eta_A_learned-1) ;

    eta_D_prop = betarnd(eta_prop_D,bet_D);
    eta_A_prop = betarnd(eta_prop_A,bet_A);


    log_lik_prop=[];

    for mmk=1:num_sub_sigs
    
        % pre-calculation of the likelihood
        log_lik_prop(mmk) = log_Likelihood(...
        ...
        app,KF_val(mmk,F_res{mmk})   , 1./KD_val(mmk,tau_fluc_D{mmk})  , KA_val(mmk,tau_fluc_A{mmk})  , eta_D_prop     , ...
        eta_A_prop                 , IRF_D_str2                    , IRF_A_str2                 , PI{mmk}        , ...
        T_pulse                    , indx1{mmk}                    , indx2{mmk}                 , sigg1{mmk}     , ...
        sigg2{mmk}                 , IRFDDstr2                     , IRFAAstr2                  , sigg11{mmk}    , ...
        sigg22{mmk}                );
    end

    bet_D1 = eta_prop_D*(1/eta_D_prop-1);
    bet_A1 = eta_prop_A*(1/eta_A_prop-1);

    % Calculate the logarithm of posterior ratio
    logr = sum(log_lik_prop-log_like)+...
           gammaln(eta_prop_D/eta_D_prop)+gammaln(bet_D)-gammaln(eta_prop_D/eta_D_learned)-gammaln(bet_D1)+...
           (eta_prop_D-alpha_eta_D)*log(eta_D_learned/eta_D_prop)+(bet_D1-beta_eta_D)*log(1-eta_D_learned)-(bet_D-beta_eta_D)*log(1-eta_D_prop)+...
           ...
           gammaln(eta_prop_A/eta_A_prop)+gammaln(bet_A)-gammaln(eta_prop_A/eta_A_learned)-gammaln(bet_A1)+...
           (eta_prop_A-alpha_eta_A)*log(eta_A_learned/eta_A_prop)+(bet_A1-beta_eta_A)*log(1-eta_A_learned)-(bet_A-beta_eta_A)*log(1-eta_A_prop);       


    % Accept or reject the proposals
         if  logr > log(rand())
             eta_D_learned        = eta_D_prop       ;
             eta_A_learned        = eta_A_prop       ;
             eta_accept           = eta_accept+1     ;
             log_like             = log_lik_prop     ;
         else
             eta_accept(2)        = eta_accept(2)+1  ;
         end
    end

end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%% Sampling FRET state trajectory through time
%  In this function we apply a forward filtering and backward sampling approach
%
%  This code is written by Sina Jazani (03/28/2023)
%  Contact: sjazani1@jhu.edu
function[ F_K ] = sampling_FRET_HMM(...
    ...
app,sigs_size   , IRF_D_str2    , IRF_A_str2  , FII       , ...
tau_A       , tau_D         , eta_D        , KF_val    , ...
eta_A       , PI            , T_pulse      , KD_val    , ...
indx1       , indx2         , sigg1        , sigg2     , ...
F11         , Num_states    , A_res        , IRFDDstr2 , ...
IRFAAstr2   , sigg11        , sigg22       )
      
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% INPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % sigs_size             :  The length of the trajectory, equivalent to number of detetcted photons
   % IRF_D_str_sqrt2       :  A pre-calculated value for the likelihood. (sqrt(2*IRF_D_str^2))
   % IRF_D_str2            :  A pre-calculated value for the likelihood. IRF_D_str^2
   % IRF_A_str_sqrt2       :  A pre-calculated value for the likelihood. (sqrt(2*IRF_A_str^2))
   % IRF_A_str2            :  A pre-calculated value for the likelihood. IRF_A_str^2
   % FII                   :  Transition probability matrixes
   % tau_A                 :  Acceptor lifetime for each donor state
   % tau_D                 :  Donor lifetime state trajectory
   % eta_D                 :  Cross-talk ratio from donor channel
   % eta_A                 :  Cross-talk ratio from acceptor channel
   % KF_val                :  FRET rate values for each FRET state
   % PI                    :  Weights on the source of photon detections (Donor , Acceptor , Donor backgound , Acceptor background)
   % T_pulse               :  Inter-pulse time
   % KD_val                :  Donor lifetime for each donor state
   % indx1                 :  Index over photons collected in the donor channel
   % indx2                 :  Index over photons collected in the acceptor channel
   % sigg1                 :  Pre-calculated values for the likelihood
   % sigg2                 :  Pre-calculated values for the likelihood
   % F11                   :  Weights on the initial FRET states
   % Num_states            :  Nember of FRET states
   % A_res                 :  Acceptor lifetime state trajectory
   
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% OUTPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % F_K                   :  The sampled FRET state trajectory
   
   
% Assign the space for the FRET state trajectory and the filter
F_K                 = nan(1         ,sigs_size);
A                   = nan(Num_states,sigs_size);
likelihood          = nan(Num_states,sigs_size);


% Pre-calculations
tau_A1              = tau_A(1,A_res(indx1))                               ;
tau_A2              = tau_A(1,A_res(indx2))                               ;

KT1                 = 1./KD_val(1,tau_D(indx1))+KF_val                    ;
KT2                 = 1./KD_val(1,tau_D(indx2))+KF_val                    ;

% AA1                 = exp(KT1.*(0.5*IRF_D_str2*KT1+sigg1)+log(erfc(IRFDDstr2*KT1+sigg11)));
% AA2                 = exp(KT2.*(0.5*IRF_A_str2*KT2+sigg2)+log(erfc(IRFAAstr2*KT2+sigg22)));

AA1                 = exp(KT1.*(0.5*IRF_D_str2*KT1+sigg1)+log_erfc(app,IRFDDstr2*KT1+sigg11));
AA2                 = exp(KT2.*(0.5*IRF_A_str2*KT2+sigg2)+log_erfc(app,IRFAAstr2*KT2+sigg22));

DD1                 = exp((0.5*IRF_D_str2./tau_A1+sigg1)./tau_A1).*erfc(IRFDDstr2./tau_A1+sigg11);
DD2                 = exp((0.5*IRF_A_str2./tau_A2+sigg2)./tau_A2).*erfc(IRFAAstr2./tau_A2+sigg22);

% Calculate the likelihood for all FRET states at any detectted photons in both channels 
likelihood(:,indx1) = (((PI(1,indx1).*AA1./KD_val(1,tau_D(indx1)))+(PI(3,indx1)./T_pulse)).*(1-eta_D))+...
                      (((PI(1,indx1).*KF_val.*((DD1-AA1)./(KT1.*tau_A1-1)))+(PI(2,indx1).*DD1./tau_A1)+(PI(4,indx1)./T_pulse)).*eta_A) ;
               
likelihood(:,indx2) = (((PI(1,indx2).*AA2./KD_val(1,tau_D(indx2)))+(PI(3,indx2)./T_pulse)).*eta_D) +...
                      (((PI(1,indx2).*KF_val.*((DD2-AA2)./(KT2.*tau_A2-1)))+(PI(2,indx2).*DD2./tau_A2)+(PI(4,indx2)./T_pulse)).*(1-eta_A)) ;

% Calculate the first filter effected by the prior on the first FRET state
A(:,1)              = F11.*likelihood(:,1)                                ;
A(:,1)              = A(:,1)./sum(A(:,1))                                 ;

% Construct the computational stable forward filters
for n = 1:(sigs_size-1)
    A(:,n+1)        = likelihood(:,n+1).*(FII(:,:,n)*A(:,n))              ;
    A(:,n+1)        = A(:,n+1)./sum(A(:,n+1))                             ;
end

% Sample the last FRET state directly from the last filter
P                   = cumsum(A(:,sigs_size))                              ;
F_K(1,sigs_size)    = find( P(end)*rand(1)<=P, 1 )                        ;

% Sample the rest of the FRET states by marching backward
for n = (sigs_size-1):-1:1
    P               = cumsum(FII(F_K(1,n+1),:,n)'.*A(:,n))                ;
    F_K(1,n)        = find( P(end)*rand(1)<=P, 1 )                        ;
end

end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%% Calculating the log of erfc(x)
% This function calculate the log(erfc(x)) with o(x^-11) for x>27.22 (Matlab limit to output inf)
%  This code is written by Sina Jazani (03/31/2023)
%  Contact: sjazani1@jhu.edu
function[d] = log_erfc(app,x)

d               = nan(size(x));
indx_high       = x>27.22;
indx_low        = x<=27.22;
d(indx_low)     = log(erfc(x(indx_low)));
x_high          = x(indx_high);
% d(indx_high) = -x(indx_high).^2 - log(x(indx_high)) + log(sqrt(pi) + 1./(2*x(indx_high)));  % o(x^-2)
% d(indx_high) = -x_high.^2 - log(x_high) - 1./(2*x_high.^2) + log(sqrt(pi) + 1./(2*x_high)); % o(x^-4)
% d(indx_high) = -x_high.^2 - log(x_high) - 1./(2*x_high.^2) - 3./(4*x_high.^4) + log(sqrt(pi) + 1./(2*x_high)); % o(x^-6)
d(indx_high)    = -x_high.^2 - log(x_high) - 1./(2*x_high.^2) - 3./(4*x_high.^4) - 15./(8*x_high.^6) - 105./(16*x_high.^8)+ log(sqrt(pi) + 1./(2*x_high)); % o(x^-11)
end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%% Sampling the state transition rates
%
%  This code is written by Sina Jazani (03/28/2023)
%  Contact: sjazani1@jhu.edu
function[ KFDA_rate_le , FDAKK , KFDA_log_like ] = sampling_Transit_rates( ...
...
app,FDA_res      , KFDA_rate_alpha  , KFDA_rate_beta   , sigs_size      , signal_time    , ...
KFDA_rate_le     , FDAKK            , Num_states       , num_sub_sigs    )
      

% Gamma +  Dirichlet direct sampling

% Parameters of hyper-prior
KFDA_PI_alp = 2 ;
KFDA_PI_bet = ones(Num_states-1,1)./(Num_states-1);

matt      = nan(Num_states);
Q         = zeros(Num_states);
TT        = zeros(Num_states,1);
for mmk=1:num_sub_sigs
    for k=1:sigs_size(mmk,1)-1
        Q(FDA_res{mmk}(k+1),FDA_res{mmk}(k))=Q(FDA_res{mmk}(k+1),FDA_res{mmk}(k))+1;
        TT(FDA_res{mmk}(k))=TT(FDA_res{mmk}(k))+signal_time{mmk}(k);
    end
end

indx=1:Num_states;
for mm=1:Num_states
    dd           = (indx~=mm);
    matt(mm,mm)  = -gamrnd(KFDA_rate_alpha+sum(Q(dd,mm)) , 1/(1./KFDA_rate_beta+TT(mm)));
    matt(dd,mm)  = -matt(mm,mm).*dirichletRnd(app,KFDA_PI_alp*KFDA_PI_bet+Q(dd,mm));
end
matt=matt-diag(diag(matt)); matt=matt-diag(sum(matt,1));

KFDA_rate_le   = matt.*(eye(Num_states)==0);
KFDA_log_like  = 0;

% Calculate the eigenvector and eigenvalue of the transition rate materix
% in order to calculate the matrix expoential in a faster way
[vecP , valP]=eig(matt); vallP = diag(valP); vec1P=vecP^-1;

for mmk=1:num_sub_sigs
    for k=1:sigs_size(mmk,1)-1
            FDAKK{mmk}(:,:,k)   = real(vecP*diag(exp(vallP*signal_time{mmk}(k)))*(vec1P));
            KFDA_log_like       = KFDA_log_like +log(FDAKK{mmk}(FDA_res{mmk}(k+1) , FDA_res{mmk}(k) , k) );
    end
end

end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%% Sampling excitation rates and background rates using Metropolis Hastings algorithm
%
%  This code is written by Sina Jazani (03/28/2023)
%  Contact: sjazani1@jhu.edu
function[ muD_ex , muA_ex  , muBD, muBA , PI_learned , PI_accept , log_lik] = sampling_pi_tau( ...
                 ... 
app,PI_accept  , IRF_D_str2      , IRF_A_str2     , F_res           , ...
tau_fluc_D     , KA_val          , eta_D          , eta_A           , ...
T_pulse        , muD_ex          , muA_ex         , muBD            , ...
muBA           , PI_learned      , p_muD_ex       , alp_muD_ex      , ...
b_muD_ex       , p_muA_ex        , alp_muA_ex     , b_muA_ex        , ...
p_muBD         , alpha_muBD      , beta_muBD      , p_muBA          , ...
alpha_muBA     , beta_muBA       , KD_val         , KF_val          , ...
indx1          , indx2           , sigg1          , sigg2           , ...
Num_states_D   , Num_states_A    , tau_fluc_A     , IRFDDstr2       , ...
IRFAAstr2      , sigg11          , sigg22         )


% pre-calculation of the likelihood
log_lik = log_Likelihood(...
        ...
app,KF_val(1,F_res)    , 1./KD_val(1,tau_fluc_D), KA_val(1,tau_fluc_A)   , eta_D        , ...
eta_A                  , IRF_D_str2             , IRF_A_str2             , PI_learned   , ...
T_pulse                , indx1                  , indx2                  , sigg1        , ...
sigg2                  , IRFDDstr2              , IRFAAstr2              , sigg11       , ...
sigg22                );

for iii=1:5

    % Proposal values of the photon weight
    muD_ex_new   = (muD_ex/p_muD_ex).*randg(p_muD_ex,1,Num_states_D) ;
    muA_ex_new   = (muA_ex/p_muA_ex).*randg(p_muA_ex,1,Num_states_A) ;
    muBD_new     = (muBD/p_muBD)*randg(p_muBD) ;
    muBA_new     = (muBA/p_muBA)*randg(p_muBA) ;

    A1           = exp(-muD_ex_new(1,tau_fluc_D));
    A2           = exp(-muA_ex_new(1,tau_fluc_A));
    A3           = exp(-muBD_new);
    A4           = exp(-muBA_new);

    PI_prop      = [(1-A1).*A2.*A3.*A4;(1-A2).*A1.*A3.*A4;(1-A3).*A1.*A2.*A4;(1-A4).*A1.*A2.*A3];
    PI_prop      = PI_prop./sum(PI_prop,1);


    log_lik_prop = log_Likelihood(...
        ...
    app,KF_val(1,F_res)    , 1./KD_val(1,tau_fluc_D), KA_val(1,tau_fluc_A)   , eta_D        , ...
    eta_A                  , IRF_D_str2             , IRF_A_str2             , PI_prop      , ...
    T_pulse                , indx1                  , indx2                  , sigg1        , ...
    sigg2                  , IRFDDstr2              , IRFAAstr2              , sigg11       , ...
    sigg22                );


% Calculate the logarithm of posterior ratio
logr = log_lik_prop-log_lik + ...
       ...
sum(((2*p_muD_ex-alp_muD_ex)*log(muD_ex./muD_ex_new))+((muD_ex-muD_ex_new)/b_muD_ex)+(p_muD_ex*((muD_ex_new./muD_ex)-(muD_ex./muD_ex_new))))+...
sum(((2*p_muA_ex-alp_muA_ex)*log(muA_ex./muA_ex_new))+((muA_ex-muA_ex_new)/b_muA_ex)+(p_muA_ex*((muA_ex_new./muA_ex)-(muA_ex./muA_ex_new))))+...
((2*p_muBD-alpha_muBD)*log(muBD./muBD_new))+((muBD-muBD_new)/beta_muBD)+(p_muBD*((muBD_new./muBD)-(muBD./muBD_new)))+...
((2*p_muBA-alpha_muBA)*log(muBA./muBA_new))+((muBA-muBA_new)/beta_muBA)+(p_muBA*((muBA_new./muBA)-(muBA./muBA_new)));


% Accept or reject the proposals
     if  logr > log(rand())
         muD_ex        = muD_ex_new     ;
         muA_ex        = muA_ex_new     ;
         muBD          = muBD_new       ; 
         muBA          = muBA_new       ;
         PI_learned    = PI_prop         ;
         PI_accept     = PI_accept+1     ;
         log_lik       = log_lik_prop    ;
     else
         PI_accept(2)  = PI_accept(2)+1  ;
     end
end

end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%% Sampling the FRET rates, Donor and acceptor lifetimes using Metropolis Hastings algorithm
%
%  This code is written by Sina Jazani (03/28/2023)
%  Contact: sjazani1@jhu.edu
function[ log_lik, KA_val , KD_val , KF_val] = sampling_pi_tau1( ...
                 ... 
app,IRF_D_str2      , IRF_A_str2      , F_Kn1_res       , tau_fluc_D      , ...
KA_val          , eta_D           , eta_A           , T_pulse         , ...
KD_val          , KF_val          , PI_learned      , indx1           , ...
indx2           , sigg1           , sigg2           , Num_states_D    , ...
Num_states_A    , tau_fluc_A      , KA_val_prop     , KA_val_alpha    , ...
KA_val_beta     , KF_val_prop     , KF_val_alpha    , KF_val_beta     , ...
KD_val_prop     , KD_val_alpha    , KD_val_beta     , Num_states_F    , ...
log_lik         , IRFDDstr2       , IRFAAstr2       , sigg11          , ...
sigg22          )

for iii=1:5

    % Proposal values of the FRET and PIFE rates
    KF_prop     = (KF_val/KF_val_prop).*randg(KF_val_prop,length(sigg1),Num_states_F) ;
    KD_prop     = (KD_val/KD_val_prop).*randg(KD_val_prop,length(sigg1),Num_states_D) ; 
    KA_prop     = (KA_val/KA_val_prop).*randg(KA_val_prop,length(sigg1),Num_states_A) ; 

    log_lik_prop=[];
    KD_propp = 1./KD_prop;
    
    % pre-calculation of the likelihood
    for mm=1:length(sigg1)
        log_lik_prop(1,mm) = log_Likelihood(...
            ...
        app,KF_prop(mm,F_Kn1_res{mm}) , KD_propp(mm,tau_fluc_D{mm}) , KA_prop(mm,tau_fluc_A{mm})  , eta_D       , ...
        eta_A                     , IRF_D_str2                  , IRF_A_str2                  , PI_learned{mm}  , ...
        T_pulse                   , indx1{mm}                   , indx2{mm}                   , sigg1{mm}       , ...
        sigg2{mm}                 , IRFDDstr2                   , IRFAAstr2                   , sigg11{mm}      , ...
        sigg22{mm}               );
    end

    % Calculate the logarithm of posterior ratio
    logr = sum(log_lik_prop-log_lik)+...
        ...
    sum(((2*KA_val_prop-KA_val_alpha)*log(KA_val./KA_prop))+...   
    ((KA_val-KA_prop)/KA_val_beta)+...
    (KA_val_prop*((KA_prop./KA_val)-(KA_val./KA_prop))))+...
        ...
    sum(((2*KF_val_prop-KF_val_alpha)*log((KF_val+eps)./(KF_prop+eps)))+...   
    ((KF_val-KF_prop)/KF_val_beta)+...
    (KF_val_prop*((KF_prop./(KF_val+eps))-(KF_val./(KF_prop+eps)))))+...
        ...
    sum(((2*KD_val_prop-KD_val_alpha)*log((KD_val+eps)./(KD_prop+eps)))+...   
    ((KD_val-KD_prop)/KD_val_beta)+...
    (KD_val_prop*((KD_prop./(KD_val+eps))-(KD_val./(KD_prop+eps)))));


    % Accept or reject the proposals
    if  logr > log(rand())
        KA_val        = KA_prop         ; 
        KD_val        = KD_prop         ;
        KF_val        = KF_prop         ;
        log_lik       = log_lik_prop    ;
    end
end

end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


%% Sampling acceptor lifetime state trajectory through time
%  In this function we apply a forward filtering and backward sampling approach
%
%  This code is written by Sina Jazani (09/14/2021)
%  Contact: sjazani1@jhu.edu
function[ A_K  ] = sampling_tau_A_HMM(...
    ...
app,sigs_size  , IRF_D_str2    , IRF_A_str2 , tau_A      , ...
F_K        , eta_D         , eta_A      , T_pulse    , ...
mu_D_ex    , mu_A_ex       , mu_BD      , D_K        , ...
mu_BA      , KD_val        , KF_val     , AII        , ...
indx1      , indx2         , sigg1      , sigg2      , ...
A11        , Num_states    , IRFDDstr2  , IRFAAstr2  , ...
sigg11     , sigg22        )
     
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% INPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % sigs_size             :  The length of the trajectory, equivalent to number of detetcted photons
   % IRF_D_str_sqrt2       :  A pre-calculated value for the likelihood. (sqrt(2*IRF_D_str^2))
   % IRF_D_str2            :  A pre-calculated value for the likelihood. IRF_D_str^2
   % IRF_A_str_sqrt2       :  A pre-calculated value for the likelihood. (sqrt(2*IRF_A_str^2))
   % IRF_A_str2            :  A pre-calculated value for the likelihood. IRF_A_str^2
   % AII                   :  Transition probability matrixes
   % tau_A                 :  Acceptor lifetime for each donor state
   % F_K                   :  FRET state trajectory
   % D_K                   :  Donor-PIFE state trajectory
   % eta_D                 :  Cross-talk ratio from donor channel
   % eta_A                 :  Cross-talk ratio from acceptor channel
   % KF_val                :  FRET rate values for each FRET state
   % T_pulse               :  Inter-pulse time
   % KD_val                :  Donor lifetime for each donor state
   % indx1                 :  Index over photons collected in the donor channel
   % indx2                 :  Index over photons collected in the acceptor channel
   % sigg1                 :  Pre-calculated values for the likelihood
   % sigg2                 :  Pre-calculated values for the likelihood
   % A11                   :  Weights on the initial acceptor lifetime states
   % Num_states            :  Nember of acceptor lifetime states
   % A_K                   :  Acceptor lifetime state trajectory
   % mu_D_ex               :  Donor excitation rates for each state
   % mu_A_ex               :  Acceptor excitation rates for each state
   % mu_BD                 :  Donor background photon emission rate
   % mu_BA                 :  Acceptor background photon emission rate
   
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% OUTPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % A_K                   :  The sampled acceptor lifetime state trajectory
   % PI                    :  Updated weights on the source of photon detections (Donor , Acceptor , Donor backgound , Acceptor background)
   
% Assign the space for the acceptor lifetime state trajectory, filter, weights on photons and the likelihood
A_K                 = nan(1          , sigs_size )                        ;
A                   = nan(Num_states , sigs_size )                        ;
PI                  = nan(Num_states , sigs_size , 4)                     ;
likelihood          = nan(Num_states , sigs_size )                        ;

% Calculate the weights on the source of photon detections (Donor , Acceptor , Donor backgound , Acceptor background)
A1                  = exp(-mu_D_ex(1,D_K))                                ;
A2                  = exp(-mu_A_ex)'                                      ;
A3                  = exp(-mu_BD)                                         ;
A4                  = exp(-mu_BA)                                         ;

PI(:,:,1)           = (1-A1).*(A2.*A3.*A4)                                ;
PI(:,:,2)           = ((1-A2).*A3.*A4).*A1                                ;
PI(:,:,3)           = ((1-A3).*A4.*A2).*A1                                ;
PI(:,:,4)           = ((1-A4).*A3.*A2).*A1                                ;
PI                  = PI./sum(PI,3)                                       ;

% Pre-calculations
KT1                 = (1./KD_val(1,D_K(indx1)))+KF_val(1,F_K(indx1))      ;
KT2                 = (1./KD_val(1,D_K(indx2)))+KF_val(1,F_K(indx2))      ;

% AA1                 = exp(KT1.*(0.5*IRF_D_str2*KT1+sigg1)+log(erfc(IRFDDstr2*KT1+sigg11)));
% AA2                 = exp(KT2.*(0.5*IRF_A_str2*KT2+sigg2)+log(erfc(IRFAAstr2*KT2+sigg22)));

AA1                 = exp(KT1.*(0.5*IRF_D_str2*KT1+sigg1)+log_erfc(app,IRFDDstr2*KT1+sigg11));
AA2                 = exp(KT2.*(0.5*IRF_A_str2*KT2+sigg2)+log_erfc(app,IRFAAstr2*KT2+sigg22));

DD1                 = exp((0.5*IRF_D_str2./tau_A+sigg1)./tau_A+log(erfc(IRFDDstr2./tau_A+sigg11)));
DD2                 = exp((0.5*IRF_A_str2./tau_A+sigg2)./tau_A+log(erfc(IRFAAstr2./tau_A+sigg22)));


% Calculate the likelihood for all acceptor lifetime states at any detectted photons in both channels 
likelihood(:,indx1) = (((PI(:,indx1,1).*AA1./KD_val(1,D_K(indx1)))+(PI(:,indx1,3)/T_pulse)).*(1-eta_D))+...
(((PI(:,indx1,1).*KF_val(1,F_K(indx1)).*((DD1-AA1)./(KT1.*tau_A-1)))+(PI(:,indx1,2).*DD1./tau_A)+(PI(:,indx1,4)/T_pulse)).*eta_A) ;
                
likelihood(:,indx2) = (((PI(:,indx2,1).*AA2./KD_val(1,D_K(indx2)))+(PI(:,indx2,3)/T_pulse)).*eta_D)+...
(((PI(:,indx2,1).*KF_val(1,F_K(indx2)).*((DD2-AA2)./(KT2.*tau_A-1)))+(PI(:,indx2,2).*DD2./tau_A)+(PI(:,indx2,4)/T_pulse)).*(1-eta_A)) ;

% Calculate the first filter effected by the prior on the first acceptor lifetime state
A(:,1)              = A11.*likelihood(:,1)                                ;
A(:,1)              = A(:,1)./sum(A(:,1))                                 ;

% Construct the computational stable forward filters
for n = 1:(sigs_size-1)
    A(:,n+1)        = likelihood(:,n+1).*(AII(:,:,n)*A(:,n))              ;
    A(:,n+1)        = A(:,n+1)./sum(A(:,n+1))                             ;
end

% Sample the last acceptor lifetime state directly from the last filter
P                   = cumsum(A(:,sigs_size))                              ;
A_K(1,sigs_size)    = find( P(end)*rand(1)<=P, 1 )                        ;

% Sample the rest of the acceptor lifetime states by marching backward
for n = (sigs_size-1):-1:1
    P               = cumsum(AII(A_K(1,n+1),:,n)'.*A(:,n))                ;
    A_K(1,n)        = find( P(end)*rand(1) <= P, 1 )                      ;
end

end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%

%% Sampling donor lifetime state trajectory through time
%  In this function we apply a forward filtering and backward sampling approach
%
%  This code is written by Sina Jazani (03/28/2023)
%  Contact: sjazani1@jhu.edu
function[ D_K , PI ] = sampling_tau_D_HMM(...
    ...
app,sigs_size  , IRF_D_str2   , IRF_A_str2 , tau_A       , ...
F_K        , eta_D        , eta_A      , T_pulse     , ...
mu_D_ex    , mu_A_ex      , mu_BD      , A_K         , ...
mu_BA      , KD_val       , KF_val     , DII         , ...
indx1      , indx2        , sigg1      , sigg2       , ...
D11        , Num_states   , IRFDDstr2  , IRFAAstr2   , ...
sigg11     , sigg22       )
      
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% INPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % sigs_size             :  The length of the trajectory, equivalent to number of detetcted photons
   % IRF_D_str_sqrt2       :  A pre-calculated value for the likelihood. (sqrt(2*IRF_D_str^2))
   % IRF_D_str2            :  A pre-calculated value for the likelihood. IRF_D_str^2
   % IRF_A_str_sqrt2       :  A pre-calculated value for the likelihood. (sqrt(2*IRF_A_str^2))
   % IRF_A_str2            :  A pre-calculated value for the likelihood. IRF_A_str^2
   % DII                   :  Transition probability matrixes
   % tau_A                 :  Acceptor lifetime for each donor state
   % F_K                   :  FRET state trajectory   
   % A_K                   :  Acceptor-PIFE state trajectory
   % eta_D                 :  Cross-talk ratio from donor channel
   % eta_A                 :  Cross-talk ratio from acceptor channel
   % KF_val                :  FRET rate values for each FRET state
   % T_pulse               :  Inter-pulse time
   % KD_val                :  Donor lifetime for each donor state
   % indx1                 :  Index over photons collected in the donor channel
   % indx2                 :  Index over photons collected in the acceptor channel
   % sigg1                 :  Pre-calculated values for the likelihood
   % sigg2                 :  Pre-calculated values for the likelihood
   % D11                   :  Weights on the initial donor lifetime states
   % Num_states            :  Nember of donor lifetime states
   % A_K                   :  Acceptor lifetime state trajectory
   % mu_D_ex               :  Donor excitation rates for each state
   % mu_A_ex               :  Acceptor excitation rates for each state
   % mu_BD                 :  Donor background photon emission rate
   % mu_BA                 :  Acceptor background photon emission rate
   
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% OUTPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % D_K                   :  The sampled donor lifetime state trajectory
   % PI                    :  Updated weights on the source of photon detections (Donor , Acceptor , Donor backgound , Acceptor background)
   
% Assign the space for the donor lifetime state trajectory, filter, weights on photons and the likelihood
D_K                 = nan(1          , sigs_size );
A                   = nan(Num_states , sigs_size );
PI                  = nan(Num_states , sigs_size , 4);
likelihood          = nan(Num_states , sigs_size );

% Calculate the weights on the source of photon detections (Donor , Acceptor , Donor backgound , Acceptor background)
A1                  = exp(-mu_D_ex)';
A2                  = exp(-mu_A_ex(1,A_K));
A3                  = exp(-mu_BD);
A4                  = exp(-mu_BA);

PI(:,:,1)           = (A3.*A4.*(1-A1)).*A2;
PI(:,:,2)           = (1-A2).*(A1.*A3.*A4);
PI(:,:,3)           = ((1-A3).*A4).*A1.*A2;
PI(:,:,4)           = ((1-A4).*A3).*A1.*A2;
PI                  = PI./sum(PI,3);


% Pre-calculations
tau_A1              = tau_A(1,A_K(indx1))            ;
tau_A2              = tau_A(1,A_K(indx2))            ;

KT1                 = (1./KD_val)+KF_val(1,F_K(indx1)) ;
KT2                 = (1./KD_val)+KF_val(1,F_K(indx2)) ;
 
AA1                 = exp(KT1.*(0.5*IRF_D_str2*KT1+sigg1)+log_erfc(app,IRFDDstr2*KT1+sigg11));
AA2                 = exp(KT2.*(0.5*IRF_A_str2*KT2+sigg2)+log_erfc(app,IRFAAstr2*KT2+sigg22));

DD1                 = exp((0.5*IRF_D_str2./tau_A1+sigg1)./tau_A1+log(erfc(IRFDDstr2./tau_A1+sigg11)));
DD2                 = exp((0.5*IRF_A_str2./tau_A2+sigg2)./tau_A2+log(erfc(IRFAAstr2./tau_A2+sigg22)));


% Calculate the likelihood for all donor states at any detectted photons in both channels 
likelihood(:,indx1) = (((PI(:,indx1,1).*AA1./KD_val)+(PI(:,indx1,3)/T_pulse)).*(1-eta_D))+...
(((PI(:,indx1,1).*KF_val(1,F_K(indx1)).*((DD1-AA1)./(KT1.*tau_A1-1)))+(PI(:,indx1,2).*DD1./tau_A1)+(PI(:,indx1,4)/T_pulse)).*eta_A) ;
                
likelihood(:,indx2) = (((PI(:,indx2,1).*AA2./KD_val)+(PI(:,indx2,3)/T_pulse)).*eta_D)+...
(((PI(:,indx2,1).*KF_val(1,F_K(indx2)).*((DD2-AA2)./(KT2.*tau_A2-1)))+(PI(:,indx2,2).*DD2./tau_A2)+(PI(:,indx2,4)/T_pulse)).*(1-eta_A)) ;


% Calculate the first filter effected by the prior on the first donor lifetime state
A(:,1)              = D11.*likelihood(:,1);
A(:,1)              = A(:,1)./sum(A(:,1)) ;

% Construct the computational stable forward filters
for n = 1:(sigs_size-1)
    A(:,n+1)        = likelihood(:,n+1).*(DII(:,:,n)*A(:,n));
    A(:,n+1)        = A(:,n+1)./sum(A(:,n+1));
end

% Sample the last acceptor lifetime state directly from the last filter
P                   = cumsum(A(:,sigs_size))                              ;
D_K(1,sigs_size)    = find( P(end)*rand(1)<=P, 1 )                        ;

% Sample the rest of the donor lifetime states by marching backward
for n = (sigs_size-1):-1:1
    P               = cumsum(DII(D_K(1,n+1),:,n)'.*A(:,n));
    D_K(1,n)        = find( P(end)*rand(1) <= P, 1 );
end

% Calculate the updatetd weights on the source of photon detections (Donor , Acceptor , Donor backgound , Acceptor background)
A1                  = exp(-mu_D_ex(1,D_K));
B                   = [(1-A1).*A2.*A3.*A4 ; (1-A2).*A1.*A3.*A4 ; (1-A3).*A1.*A2.*A4 ; (1-A4).*A1.*A2.*A3 ];
PI                  = (B./sum(B,1)) ;

end
%%%%%%%%%%%%%%%%%%%%%%%%%   End of the function   %%%%%%%%%%%%%%%%%%%%%%%%


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
     
    
    clc
    app.Gauge.Value=0;
    drawnow
    app.GenerateButton.Enable              = 'off';
    app.ImportButton.Enable                = 'off';
    app.RunButton.Enable                   = 'off'; 
    app.HelpButton.Enable                  = 'off';
    app.LicenseButton.Enable               = 'off';
    app.FRETstatesEditField.Enable         = 'off';
    app.acceptorPIFEstatesEditField.Enable = 'off';
    app.donorPIFEstatesEditField.Enable    = 'off';
    app.ContinueButton.Enable              = 'off';
    app.PreviouslyanalyzedfileButton.Enable= 'off';


%     choice = questdlg({'Please rename the param.mat file if you want to keep it!! Do you want to continue?'},...
%                        'Sample analysis','Yes','No','Yes');
    
%     if  strcmp(choice,'Yes')

%     if isequal(exist('param.mat','file'),2)
%        S = dir('param.mat'); 
%        delete(S.name)
%     end


    % Set the name of the new file based on the date
    c                = round(clock);
    Name_date        = [];
    if     c(3)<10
           Name_date = ['0',num2str(c(3))];
    else
           Name_date = [num2str(c(3))];
    end
    if     c(2)<10
           Name_date = [Name_date,'0',num2str(c(2))];
    else
           Name_date = [Name_date,num2str(c(2))];
    end
    Name_date        = [Name_date,num2str(c(1)),'_1'];

    opts.Interpreter = 'tex';
    answer1           = inputdlg({'Name'},'Name of the file to save:',[1 60],{Name_date},opts);

    if length(answer1)==1
       while isempty(answer1{1})
           opts.Interpreter = 'tex';
           answer1           = inputdlg({'Name'},'Name of the file to save:',[1 60],{Name_date},opts);
       end
    Name_date              = answer1{1};
    app.FilenameLabel.Text = ['File name : ',Name_date] ;


    cla(app.UIAxes)
    cla(app.UIAxes_2)
    cla(app.UIAxes_3)
    cla(app.UIAxes_4)
    cla(app.UIAxes_5)
    cla(app.UIAxes_6)
    cla(app.UIAxes_7)
    cla(app.UIAxes_8)

    opts.Interpreter = 'tex';
    answer = inputdlg({ 'Length of the time trace (s)',...
                        'Pulse period (ns)',...
                        '\mu_{back,D} (s^{-1})',...
                        '\mu_{back,A} (s^{-1})',...
                        '\mu_{ex,D} (per pulse)',...
                        '\mu_{ex,A} (per pulse)',...
                        '\tau_{D} (ns)' ,...
                        '\tau_{A} (ns)' ,...
                        'K_F (ns^{-1})',...
                        '\mu_{IRF,D} (ns)',...
                        '\sigma_{IRF,D} (ns)',...
                        '\mu_{IRF,A} (ns)',...
                        '\sigma_{IRF,A} (ns)',...
                        'Q_D',...
                        'Q_A',...
                        'Q_F',...
                        '\eta_D', '\eta_A'},...
                        'Parameters of a synthetis time trace',...
                        [ 1 60 ; 1 60 ; 1 60 ;1 60 ; 1 60 ; 1 60 ;...
                          1 60 ; 1 60 ; 1 60 ;1 60 ; 1 60 ; 1 60 ;...
                          1 60 ; 1 60 ; 1 60 ;1 60 ; 1 60 ; 1 60],...
                       {'.1','50','500','500','0.001, 0.002','0.00001, 0.00004',...
                       '2, 4','2, 3.8','0.1 2',...
                       '1.914','0.26','1.934','0.25',...
                       '-10,20;10,-20',...
                       '-10,20;10,-20',...
                       '-10,20;10,-20','0.1','0.1'},opts);


if length(answer)==18
    while isempty(str2num(answer{1})) || isempty(str2num(answer{2})) ||...
          isempty(str2num(answer{3})) || isempty(str2num(answer{4})) ||...
          isempty(str2num(answer{5})) || isempty(str2num(answer{6})) ||...
          isempty(str2num(answer{7})) || isempty(str2num(answer{8})) ||...
          isempty(str2num(answer{9})) || isempty(str2num(answer{10})) ||...
          isempty(str2num(answer{11})) || isempty(str2num(answer{12})) ||...
          isempty(str2num(answer{13})) || isempty(str2num(answer{14})) ||...
          isempty(str2num(answer{15})) || isempty(str2num(answer{16})) ||...
          isempty(str2num(answer{17})) || isempty(str2num(answer{18})) 
          
          
    opts.Interpreter = 'tex';
    answer = inputdlg({ 'Length of the time trace (s)',...
                        'Pulse period (ns)',...
                        '\mu_{back,D} (s^{-1})',...
                        '\mu_{back,A} (s^{-1})',...
                        '\mu_{ex,D} (per pulse)',...
                        '\mu_{ex,A} (per pulse)',...
                        '\tau_{D} (ns)' ,...
                        '\tau_{A} (ns)' ,...
                        'K_F (ns^{-1})',...
                        '\mu_{IRF,D} (ns)',...
                        '\sigma_{IRF,D} (ns)',...
                        '\mu_{IRF,A} (ns)',...
                        '\sigma_{IRF,A} (ns)',...
                        'Q_D',...
                        'Q_A',...
                        'Q_F',...
                        '\eta_D', '\eta_A'},...
                        'Please check the parameters',...
                        [ 1 60 ; 1 60 ; 1 60 ;1 60 ; 1 60 ; 1 60 ;...
                          1 60 ; 1 60 ; 1 60 ;1 60 ; 1 60 ; 1 60 ;...
                          1 60 ; 1 60 ; 1 60 ;1 60 ; 1 60 ; 1 60],...
                       {'.1','50','500','500','0.001, 0.002','0.00001, 0.00004',...
                       '2, 4','2, 3.8','0.1 2',...
                       '1.914','0.26','1.934','0.25',...
                       '-10,20;10,-20',...
                       '-10,20;10,-20',...
                       '-10,20;10,-20','0.1','0.1'},opts);
    end

 
    Data.Num_pulses                       = floor(str2num(answer{1})*10^9./str2num(answer{2}));
    app.LengthsEditField.Value            = str2num(answer{1});

    Data.pulse_priod                      = str2num(answer{2});
    app.PulseperiodnsEditField.Value      = Data.pulse_priod;

    Data.Back_D_real                      = str2num(answer{3});
    app.mu_textbackDphtssEditField.Value  = answer{3};

    Data.Back_A_real                      = str2num(answer{4});
    app.mu_textbackAphtssEditField.Value  = answer{4};

    Data.ex_D_real                        = str2num(answer{5});
    app.mu_textexDexcpulseEditField.Value = answer{5};

    Data.ex_A_real                        = str2num(answer{6});
    app.mu_textexAexcpulseEditField.Value = answer{6};

    Data.KD_val_real                      = str2num(answer{7});
    app.tau_textDnsEditField.Value        = answer{7};

    Data.KA_val_real                      = str2num(answer{8});
    app.tau_textAnsEditField.Value        = answer{8};

    Data.KF_val_real                      = str2num(answer{9});
    app.K_textFRETns1EditField.Value      = answer{9};
    
    Data.IRF_mean(1)                      = str2num(answer{10});
    app.mu_textIRFDnsEditField.Value      = Data.IRF_mean(1);

    Data.IRF_str(1)                       = str2num(answer{11});
    app.sigma_textIRFDnsEditField.Value   = Data.IRF_str(1) ;

    Data.IRF_mean(2)                      = str2num(answer{12});
    app.mu_textIRFAnsEditField.Value      = Data.IRF_mean(2);

    Data.IRF_str(2)                       = str2num(answer{13});
    app.sigma_textIRFAnsEditField.Value   = Data.IRF_str(2);

    Data.KD_real                          = str2num(answer{14});
    app.overlineoverlineQ_DEditField.Value= answer{14};

    Data.KA_real                          = str2num(answer{15});
    app.overlineoverlineQ_AEditField.Value= answer{15};

    Data.KF_real                          = str2num(answer{16});
    app.overlineoverlineQ_FEditField.Value= answer{16};

    Data.eta_D_real                       = str2num(answer{17});
    app.eta_textDEditField.Value          = Data.eta_D_real;

    Data.eta_A_real                       = str2num(answer{18});
    app.eta_textAEditField.Value          = Data.eta_A_real;
    
    app.FRETstatesEditField.Value         = length(Data.KF_val_real);
    app.acceptorPIFEstatesEditField.Value = length(Data.KA_val_real);
    app.donorPIFEstatesEditField.Value    = length(Data.KD_val_real);


% Generate the synthetic time trace
    Data                                  = Generative_model(app,Data) ;

    app.NumberphotonsEditField.Value      = size(Data.signal,2);

    save(Name_date,'Data')


axInnerPos = app.UIAxes.InnerPosition;  
scatter(app.UIAxes,Data.signal(2,Data.signal(3,:)==1),Data.signal(1,Data.signal(3,:)==1),1,'MarkerEdgeColor','none','MarkerFaceColor','g')
ylim(app.UIAxes,[0 Data.pulse_priod])
xlim(app.UIAxes,[0 max(Data.signal(2,:))]); box(app.UIAxes,'off')
innerPosDiff1       = axInnerPos - app.UIAxes.InnerPosition;  
app.UIAxes.Position = app.UIAxes.Position + innerPosDiff1;

axInnerPos1 = app.UIAxes_2.InnerPosition; 
scatter(app.UIAxes_2,Data.signal(2,Data.signal(3,:)==2),Data.signal(1,Data.signal(3,:)==2),1,'MarkerEdgeColor','none','MarkerFaceColor','r')
ylim(app.UIAxes_2,[0 Data.pulse_priod])
app.UIAxes_2.YDir = 'reverse'; app.UIAxes_2.XAxisLocation ='origin';box(app.UIAxes_2,'off')
xlim(app.UIAxes_2,[0 max(Data.signal(2,:))]);
innerPosDiff2            = axInnerPos1 - app.UIAxes_2.InnerPosition;  
app.UIAxes_2.Position    = app.UIAxes_2.Position + innerPosDiff2;
app.UIAxes_2.Position(2) = app.UIAxes.Position(2)-app.UIAxes_2.Position(4)+12;

axInnerPos2 = app.UIAxes_3.InnerPosition; 
stairs(app.UIAxes_3,Data.signal(2,:),Data.F_fluc_real)
ylim(app.UIAxes_3,[0 max([1 max(Data.F_fluc_real)*1.1])])
xlim(app.UIAxes_3,[0 max(Data.signal(2,:))]); box(app.UIAxes_3,'off')
innerPosDiff3        = axInnerPos2 - app.UIAxes_3.InnerPosition;  
app.UIAxes_3.Position = app.UIAxes_3.Position + innerPosDiff3;

axInnerPos3 = app.UIAxes_4.InnerPosition; 
stairs(app.UIAxes_4,Data.signal(2,:),Data.tau_D_fluc_real)
ylim(app.UIAxes_4,[0 max([1 max(Data.tau_D_fluc_real)*1.1])])
xlim(app.UIAxes_4,[0 max(Data.signal(2,:))]); box(app.UIAxes_4,'off')
innerPosDiff4        = axInnerPos3 - app.UIAxes_4.InnerPosition;  
app.UIAxes_4.Position = app.UIAxes_4.Position + innerPosDiff4;

axInnerPos4 = app.UIAxes_5.InnerPosition; 
stairs(app.UIAxes_5,Data.signal(2,:),Data.tau_A_fluc_real)
ylim(app.UIAxes_5,[0 max([1 max(Data.tau_A_fluc_real)*1.1])]); box(app.UIAxes_5,'off')
xlim(app.UIAxes_5,[0 max(Data.signal(2,:))]);
xlabel(app.UIAxes_5,'Time (s)')
innerPosDiff5         = axInnerPos4 - app.UIAxes_5.InnerPosition;  
app.UIAxes_5.Position = app.UIAxes_5.Position + innerPosDiff5;
end
   end
    app.GenerateButton.Enable            ='on';
    app.ImportButton.Enable              ='on';
    app.HelpButton.Enable                ='on';
    app.LicenseButton.Enable             ='on';
    app.RunButton.Enable                 ='on';
    app.PreviouslyanalyzedfileButton.Enable='on';


        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)

    
    clc

    % Set the name of the new file based on the date
    c                = round(clock);
    Name_date        = [];
    if     c(3)<10
           Name_date = ['0',num2str(c(3))];
    else
           Name_date = [num2str(c(3))];
    end
    if     c(2)<10
           Name_date = [Name_date,'0',num2str(c(2))];
    else
           Name_date = [Name_date,num2str(c(2))];
    end
    Name_date        = [Name_date,num2str(c(1)),'_1'];
    opts.Interpreter = 'tex';
    answer1           = inputdlg({'Name'},'Name of the file to save:',[1 60],{Name_date},opts);

    if length(answer1)==1
       while isempty(answer1{1})
           opts.Interpreter = 'tex';
           answer1           = inputdlg({'Name'},'Name of the file to save:',[1 60],{Name_date},opts);
           
       end
    Name_date              = answer1{1};
    app.FilenameLabel.Text = ['File name : ',Name_date] ;

    app.Gauge.Value=0;
    drawnow
    app.GenerateButton.Enable              = 'off';
    app.ImportButton.Enable                = 'off';
    app.RunButton.Enable                   = 'off';           
    app.HelpButton.Enable                  = 'off';
    app.LicenseButton.Enable               = 'off';
    app.FRETstatesEditField.Enable         = 'off';
    app.acceptorPIFEstatesEditField.Enable = 'off';
    app.donorPIFEstatesEditField.Enable    = 'off';
    app.ContinueButton.Enable              = 'off';
    app.PreviouslyanalyzedfileButton.Enable='off';

% choice = questdlg({'Please rename the param.mat file if you want to keep it!! Do you want to continue?'},...
%                        'Sample analysis','Yes','No','Yes');
    
%     if  strcmp(choice,'Yes')
  
    opts.Interpreter = 'tex';
    answer = inputdlg({'Pulse period (ns)',...
                        '\mu_{IRF,D} (ns)',...
                        '\sigma_{IRF,D} (ns)',...
                        '\mu_{IRF,A} (ns)',...
                        '\sigma_{IRF,A} (ns)'},...
                        'Parameters',...
                        [ 1 60 ; 1 60 ; 1 60 ;1 60 ; 1 60 ],...
                       {'50','1','0.26','1','0.24'},opts);
  
    if length(answer)==5
    while isempty(str2num(answer{1})) || str2num(answer{1})==0 ||...
          isempty(str2num(answer{2})) || str2num(answer{2})==0 ||...
          isempty(str2num(answer{3})) || str2num(answer{3})==0 ||...
          isempty(str2num(answer{4})) || str2num(answer{4})==0 ||...
          isempty(str2num(answer{5})) || str2num(answer{5})==0

          opts.Interpreter = 'tex';
          answer = inputdlg({'Pulse period (ns)',...
                        '\mu_{IRF,D} (ns)',...
                        '\sigma_{IRF,D} (ns)',...
                        '\mu_{IRF,A} (ns)',...
                        '\sigma_{IRF,A} (ns)'},...
                        'Please check the parameters',...
                        [ 1 60 ; 1 60 ; 1 60 ;1 60 ; 1 60 ],...
                       {'50','1','0.26','1','0.24'},opts);
    end

    Data.pulse_priod    =str2num(answer{1});
    Data.IRF_mean(1)    =str2num(answer{2});
    Data.IRF_str(1)     =str2num(answer{3});
    Data.IRF_mean(2)    =str2num(answer{4});
    Data.IRF_str(2)     =str2num(answer{5});


    app.PulseperiodnsEditField.Value      = Data.pulse_priod;
    app.mu_textIRFDnsEditField.Value      = Data.IRF_mean(1);
    app.sigma_textIRFDnsEditField.Value   = Data.IRF_str(1) ;
    app.mu_textIRFAnsEditField.Value      = Data.IRF_mean(2);
    app.sigma_textIRFAnsEditField.Value   = Data.IRF_str(2);


% Find the file from the directory
% commandwindow();
    [filename, pathname] = uigetfile( { '*.txt','Text-files (*.txt)';...
                                        '*.mat','MAT-files (*.mat)' }, ...
                                        'Import a time series', ...
                                        'MultiSelect', 'off');

% Check the filename                             
    if ~isequal(filename,0)
        
% Load the file

    load(fullfile(pathname,filename))
    Data.signals{1}=signals;

    save(Name_date,'Data')

    Data.Num_pulses                       = Data.signals{1}(2,end)*10^9./Data.pulse_priod;
    app.LengthsEditField.Value            = Data.signals{1}(2,end);
    app.NumberphotonsEditField.Value      = size(Data.signals{1},2);
    cla(app.UIAxes)
    cla(app.UIAxes_2)
    cla(app.UIAxes_3)
    cla(app.UIAxes_4)
    cla(app.UIAxes_5)
    cla(app.UIAxes_6)
    cla(app.UIAxes_7)
    cla(app.UIAxes_8)

axInnerPos = app.UIAxes.InnerPosition;  
scatter(app.UIAxes,Data.signals{1}(2,Data.signals{1}(3,:)==1),Data.signals{1}(1,Data.signals{1}(3,:)==1),1,'MarkerEdgeColor','none','MarkerFaceColor','g')
ylim(app.UIAxes,[0 Data.pulse_priod])
xlim(app.UIAxes,[0 max(Data.signals{1}(2,:))]);
app.UIAxes.XTickLabel=[];box(app.UIAxes,'off')
innerPosDiff1        = axInnerPos - app.UIAxes.InnerPosition;  
app.UIAxes.Position = app.UIAxes.Position + innerPosDiff1;

axInnerPos1 = app.UIAxes_2.InnerPosition; 
scatter(app.UIAxes_2,Data.signals{1}(2,Data.signals{1}(3,:)==2),Data.signals{1}(1,Data.signals{1}(3,:)==2),1,'MarkerEdgeColor','none','MarkerFaceColor','r')
ylim(app.UIAxes_2,[0 Data.pulse_priod])
app.UIAxes_2.YDir = 'reverse'; app.UIAxes_2.XAxisLocation ='origin';box(app.UIAxes_2,'off')
xlim(app.UIAxes_2,[0 max(Data.signals{1}(2,:))]);
app.UIAxes_2.XTickLabel=[];
innerPosDiff2            = axInnerPos1 - app.UIAxes_2.InnerPosition;  
app.UIAxes_2.Position   = app.UIAxes_2.Position + innerPosDiff2;
app.UIAxes_2.Position(2)=app.UIAxes.Position(2)-app.UIAxes_2.Position(4)+12;
    
%     end
    end
    end
    app.GenerateButton.Enable            ='on';
    app.ImportButton.Enable              ='on';
    app.HelpButton.Enable                ='on';
    app.LicenseButton.Enable             ='on';
    app.RunButton.Enable                 ='on';
    app.PreviouslyanalyzedfileButton.Enable='on';
    end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

    load([app.FilenameLabel.Text(13:end),'.mat']);   
    app.Gauge.Value=0;
    drawnow

    cla(app.UIAxes_3)
    cla(app.UIAxes_4)
    cla(app.UIAxes_5)
    cla(app.UIAxes_6)
    cla(app.UIAxes_7)
    cla(app.UIAxes_8)
    
    if isfield(Data,'KA_val_learned')

       choice = questdlg({'The Run button will overwrite the previously sampled values!! Do you want to continue?'},...
                          'Warning','Yes','No','Yes');
    
       if  strcmp(choice,'Yes')
    
    if isfield(Data,'F_fluc_real')

        NF=num2str(length(Data.KF_val_real));
        ND=num2str(length(Data.KD_val_real));
        NA=num2str(length(Data.KA_val_real));
    else
        NF='2';
        ND='1';
        NA='1';
    end

    app.GenerateButton.Enable            ='off';
    app.ImportButton.Enable              ='off';
    app.RunButton.Enable                 ='off';
    app.HelpButton.Enable                ='off';
    app.LicenseButton.Enable             ='off';
    app.ContinueButton.Enable            ='off';
    app.PreviouslyanalyzedfileButton.Enable='off';

    opts.Interpreter = 'tex';

    answer = inputdlg({ 'Number of FRET states',...
                        'Number of Donor-PIFE states',...
                        'Number of Acceptor-PIFE states',...
                        'Save size','Number iterations','Burn-in percentage'},...
                        'Please check the parameters',...
                        [ 1 60 ; 1 60 ; 1 60 ;1 60  ;1 60;1 60],...
                       {NF,ND,NA,'10','10000','80'},opts);
    if length(answer)==6
    while isempty(str2num(answer{1})) || str2num(answer{1})==0 ||...
          isempty(str2num(answer{2})) || str2num(answer{2})==0 ||...
          isempty(str2num(answer{3})) || str2num(answer{3})==0 ||...
          isempty(str2num(answer{4})) || str2num(answer{4})==0 

          opts.Interpreter = 'tex';
          answer = inputdlg({'Number of FRET states',...
                             'Number of Donor-PIFE states',...
                             'Number of Acceptor-PIFE states',...
                             'Save size','Number iterations','Burn-in percentage'},...
                             'Please check the parameters',...
                             [ 1 60 ; 1 60 ; 1 60 ;1 60 ;1 60 ;1 60],...
                             {NF,ND,NA,'10','10000','80'},opts);
    end

    Data.Num_states_F     = str2num(answer{1});
    Data.Num_states_D     = str2num(answer{2});
    Data.Num_states_A     = str2num(answer{3});
    Data.save_size        = str2num(answer{4});
    Max_iter              = str2num(answer{5});
    burn_in_perc          = str2num(answer{6});
    

    Data.sample_eta       = true; 

    app.FRETstatesEditField.Value         = Data.Num_states_F;
    app.acceptorPIFEstatesEditField.Value = Data.Num_states_A;
    app.donorPIFEstatesEditField.Value    = Data.Num_states_D;
    app.SavesizeEditField.Value           = Data.save_size;
    app.iterationsEditField.Value         = Max_iter;
    app.BurninEditField.Value             = burn_in_perc;


Data.KF_rate_alpha         =      1    ;
Data.KF_rate_beta          =  10000    ;

Data.F_all_alpha           =      1    ;

Data.KD_rate_alpha         =      1    ;
Data.KD_rate_beta          =  10000    ;

Data.D_all_alpha           =      1    ;

Data.KA_rate_alpha         =      1    ;
Data.KA_rate_beta          =  10000    ;

Data.A_all_alpha           =      1    ;

Data.prop_mu_D_ex          =   1000    ;
Data.alpha_mu_D_ex         =      1    ;
Data.beta_mu_D_ex          =      0.01 ;

Data.prop_mu_A_ex          =   1000    ;
Data.alpha_mu_A_ex         =      1    ;
Data.beta_mu_A_ex          =      0.01 ;

Data.prop_mu_BD            =  10000    ;
Data.alpha_mu_BD           =      1    ;
Data.beta_mu_BD            =      0.001;

Data.prop_mu_BA            =  10000    ;
Data.alpha_mu_BA           =      1    ;
Data.beta_mu_BA            =      0.001 ;

Data.sample_eta            =   true    ;
% Cross-talks

Data.eta_prop_D            =      0.5    ;
Data.alpha_eta_D           =      0.5    ;
Data.beta_eta_D            =     10    ;

Data.eta_prop_A            =      0.5    ;
Data.alpha_eta_A           =      0.5    ;
Data.beta_eta_A            =     10    ;
if  Data.sample_eta 
    Data.eta_D_learned         = betarnd(Data.alpha_eta_D , Data.beta_eta_D) ;
    Data.eta_A_learned         = betarnd(Data.alpha_eta_A , Data.beta_eta_A) ;
else % pre calibrated values
    Data.eta_D_learned     =      0.06;
    Data.eta_A_learned     =      0.06;
end

Data.KF_val_prop           =  1000       ;
Data.KF_val_alpha          =      1      ;
Data.KF_val_beta           =     10      ;

Data.KD_val_prop           =  1000       ;
Data.KD_val_alpha          =      2   ;
Data.KD_val_beta           =      4      ;

Data.KA_val_prop           =  1000       ;
Data.KA_val_alpha          =      2   ;
Data.KA_val_beta           =      4      ;


if  isfield(Data,'KD_real')
    Data.signals           = []          ; 
    
    Data.signals{1}        = Data.signal ;
end

Data.save_on_off           = true   ;

Data                       = Initiation_param(app,Data);

% Run the algorithm
Data                       = Gibbs_sampler(app, Data ,  Max_iter );


burn_in      = max([1 floor((burn_in_perc/100)*length(Data.F_all(1,:)))]);

Data_F = Data;sigg_n2_F = []; max_F(1)=0;
for mme=1:Data.num_sub_sigs
    if  mme==1
        sigg_n2_F =Data.signals{mme}(2,:); max_F(2) = max(unique(sigg_n2_F));
    else
        sigg_n2_F =[sigg_n2_F, max_F(mme)+Data.signals{mme}(2,:)]; max_F(mme+1) = max(sigg_n2_F);
    end
end


% Plot F trajectory
axInnerPos2 = app.UIAxes_3.InnerPosition; 
if isfield(Data,'F_fluc_real')
stairs(app.UIAxes_3,Data.signals{1}(2,:),Data.F_fluc_real,'g')
end
ress=[]; F_Ind_p=[]; map_F=[]; hold(app.UIAxes_3,'on')
for mmk=1:Data.num_sub_sigs
    F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
for ii=1:size(Data.F_all(1,burn_in:end),2)
    ttf(:,ii)=Data.KF_val_learned{mmk}(burn_in+ii-1,Data.F_res{mmk}(burn_in+ii-1,:));
end
    ress            = [ress,ttf']; map_F     = [map_F,Data.map_KF_val(mmk,Data.map_F_res{mmk})];
end
F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

hr11=stairs(app.UIAxes_3,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
hr22=fill(app.UIAxes_3,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
hr33=stairs(app.UIAxes_3,F_Ind_p,map_F,'-.','Color','m'); box(app.UIAxes_3,'off')
if isfield(Data,'F_fluc_real')
   F_maxx = max([1,max([Data.F_fluc_real,max(F_m_up1)])*1.1]);
else
   F_maxx = max([1,max(F_m_up1)*1.1]);
end
ylim(app.UIAxes_3,[0 F_maxx])
xlim(app.UIAxes_3,[0 max(Data.signals{1}(2,:))]);
app.UIAxes_3.XTickLabel=[];hold(app.UIAxes_3,'off')
innerPosDiff3         = axInnerPos2 - app.UIAxes_3.InnerPosition;  
app.UIAxes_3.Position = app.UIAxes_3.Position + innerPosDiff3;


% Plot Donor lifetime trajectory
axInnerPos3 = app.UIAxes_4.InnerPosition; 
if isfield(Data,'F_fluc_real')
stairs(app.UIAxes_4,Data.signals{1}(2,:),Data.tau_D_fluc_real,'g')
end
ress=[]; F_Ind_p=[]; map_D=[]; hold(app.UIAxes_4,'on')
for mmk=1:Data.num_sub_sigs
    F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
for ii=1:size(Data.D_all(1,burn_in:end),2)
    ttf(:,ii)=Data.KD_val_learned{mmk}(burn_in+ii-1,Data.tau_D_res{mmk}(burn_in+ii-1,:));
end
    ress            = [ress,ttf']; map_D     = [map_D,Data.map_KD_val(mmk,Data.map_tau_D{mmk})];
end
F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

stairs(app.UIAxes_4,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
fill(app.UIAxes_4,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
stairs(app.UIAxes_4,F_Ind_p,map_D,'-.','Color','m');box(app.UIAxes_4,'off')
if  isfield(Data,'F_fluc_real')
    D_maxx=  max([1,max(Data.tau_D_fluc_real)*1.1,max(F_m_up1)*1.1]);
else
    D_maxx=  max([1,max(F_m_up1)*1.1]);
end
ylim(app.UIAxes_4,[0 D_maxx])
xlim(app.UIAxes_4,[0 max(Data.signals{1}(2,:))]);
app.UIAxes_4.XTickLabel=[];hold(app.UIAxes_4,'off')
innerPosDiff4        = axInnerPos3 - app.UIAxes_4.InnerPosition;  
app.UIAxes_4.Position = app.UIAxes_4.Position + innerPosDiff4;


% Plot Acceptor lifetime trajectory
axInnerPos4 = app.UIAxes_5.InnerPosition; 
if isfield(Data,'F_fluc_real')
stairs(app.UIAxes_5,Data.signals{1}(2,:),Data.tau_A_fluc_real,'g')
end
ress=[]; F_Ind_p=[]; map_F=[]; hold(app.UIAxes_5,'on')
for mmk=1:Data.num_sub_sigs
    F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
for ii=1:size(Data.A_all(1,burn_in:end),2)
    ttf(:,ii)=Data.KA_val_learned{mmk}(burn_in+ii-1,Data.tau_A_res{mmk}(burn_in+ii-1,:));
end
    ress            = [ress,ttf']; map_F     = [map_F,Data.map_KA_val(mmk,Data.map_tau_A{mmk})];
end
F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

stairs(app.UIAxes_5,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
fill(app.UIAxes_5,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
stairs(app.UIAxes_5,F_Ind_p,map_F,'-.','Color','m');
if  isfield(Data,'F_fluc_real')
    A_maxx=max([1,max(Data.tau_A_fluc_real)*1.1,max(F_m_up1)*1.1]);
else
    A_maxx=max([1,max(F_m_up1)*1.1]);
end
ylim(app.UIAxes_5,[0 A_maxx]);

xlim(app.UIAxes_5,[0 max(Data.signals{1}(2,:))]);
box(app.UIAxes_5,'off') ; hold(app.UIAxes_5,'off')
innerPosDiff5         = axInnerPos4 - app.UIAxes_5.InnerPosition;  
app.UIAxes_5.Position = app.UIAxes_5.Position + innerPosDiff5;


% Plot the FRET transition rates
if Data.Num_states_F>1
edgehF=linspace(0,max(max(max(Data.KF_rate_learned(:,:,burn_in:end)))),50);
SS_F_max=0;
for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if m~=n
SS=histcounts(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),edgehF,'Normalization','pdf');
if max(SS)>SS_F_max
    SS_F_max = max(SS);
end
            end
        end
end
if  isfield(Data,'F_fluc_real')
    for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if m~=n
line(app.UIAxes_8,[1 1]*Data.KF_real(m,n),[0 1]*SS_F_max*.1,'LineStyle','-.','Color','g')
            hold(app.UIAxes_8,'on')
            end
        end
    end
end
for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if m~=n
line(app.UIAxes_8,[1 1]*Data.map_KF_rate(m,n),[0 1]*SS_F_max.*1.1,'LineStyle','-.','Color','b')
hold(app.UIAxes_8,'on')
xx1=[quantile(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
     quantile(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
fill(app.UIAxes_8,[xx1,fliplr(xx1)],[0 0 1 1]*SS_F_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
histogram(app.UIAxes_8,permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),edgehF,'Normalization','pdf');
            end
        end
end
xlim(app.UIAxes_8,[0,max(max(max(Data.KF_rate_learned(:,:,burn_in:end))))])
hold(app.UIAxes_8,'off'); box(app.UIAxes_8,'off')
end


% Plot the Acceptor_PIFE transition rates
if Data.Num_states_A>1
edgehA=linspace(0,max(max(max(Data.KA_rate_learned(:,:,burn_in:end)))),50);
SS_A_max=0;
for m=1:Data.Num_states_A
        for n=1:Data.Num_states_A
            if m~=n
SS=histcounts(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),edgehA,'Normalization','pdf');
if max(SS)>SS_A_max
    SS_A_max = max(SS);
end
            end
        end
end
if  isfield(Data,'F_fluc_real')
    for m=1:Data.Num_states_A
        for n=1:Data.Num_states_A
            if m~=n
line(app.UIAxes_7,[1 1]*Data.KA_real(m,n),[0 1]*SS_A_max*.1,'LineStyle','-.','Color','g')
           hold(app.UIAxes_7,'on')
            end
        end
    end
end
for m=1:Data.Num_states_A
        for n=1:Data.Num_states_A
            if m~=n
line(app.UIAxes_7,[1 1]*Data.map_KA_rate(m,n),[0 1]*SS_A_max.*1.1,'LineStyle','-.','Color','b')
hold(app.UIAxes_7,'on')
xx1=[quantile(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
     quantile(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
fill(app.UIAxes_7,[xx1,fliplr(xx1)],[0 0 1 1]*SS_A_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
histogram(app.UIAxes_7,permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),edgehA,'Normalization','pdf');
            end
        end
end
xlim(app.UIAxes_7,[0,max(max(max(Data.KA_rate_learned(:,:,burn_in:end))))])
hold(app.UIAxes_7,'off'); box(app.UIAxes_7,'off')
end


% Plot the Donor_PIFE transition rates
if Data.Num_states_D>1
edgehP=linspace(0,max(max(max(Data.KD_rate_learned(:,:,burn_in:end)))),50);
SS_P_max=0;
for m=1:Data.Num_states_D
        for n=1:Data.Num_states_D
            if m~=n
SS=histcounts(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),edgehP,'Normalization','pdf');
if max(SS)>SS_P_max
    SS_P_max = max(SS);
end
            end
        end
end
if  isfield(Data,'F_fluc_real')
    for m=1:Data.Num_states_D
        for n=1:Data.Num_states_D
            if m~=n
line(app.UIAxes_6,[1 1]*Data.KD_real(m,n),[0 1]*SS_P_max*1.1,'LineStyle','-.','Color','g')
            hold(app.UIAxes_6,'on')
            end
        end
    end
end
for m=1:Data.Num_states_D
        for n=1:Data.Num_states_D
            if m~=n
line(app.UIAxes_6,[1 1]*Data.map_KD_rate(m,n),[0 1]*SS_P_max.*1.1,'LineStyle','-.','Color','b')
hold(app.UIAxes_6,'on')
xx1=[quantile(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
     quantile(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
fill(app.UIAxes_6,[xx1,fliplr(xx1)],[0 0 1 1]*SS_P_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
histogram(app.UIAxes_6,permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),edgehP,'Normalization','pdf');
            end
        end
end
xlim(app.UIAxes_6,[0,max(max(max(Data.KD_rate_learned(:,:,burn_in:end))))])
hold(app.UIAxes_6,'off'); box(app.UIAxes_6,'off')
end


  end

    app.GenerateButton.Enable            ='on';
    app.ImportButton.Enable              ='on';
    app.RunButton.Enable                 ='on';
    app.HelpButton.Enable                ='on';
    app.LicenseButton.Enable             ='on';
    app.PreviouslyanalyzedfileButton.Enable='on';
    if isfield(Data,'KF_val_learned')
       app.ContinueButton.Enable         ='on';
    end
       end
    else

if isfield(Data,'F_fluc_real')

        NF=num2str(length(Data.KF_val_real));
        ND=num2str(length(Data.KD_val_real));
        NA=num2str(length(Data.KA_val_real));
    else
        NF='2';
        ND='1';
        NA='1';
    end

    app.GenerateButton.Enable            ='off';
    app.ImportButton.Enable              ='off';
    app.RunButton.Enable                 ='off';
    app.HelpButton.Enable                ='off';
    app.LicenseButton.Enable             ='off';
    app.ContinueButton.Enable            ='off';
    app.PreviouslyanalyzedfileButton.Enable='off';

    opts.Interpreter = 'tex';

    answer = inputdlg({ 'Number of FRET states',...
                        'Number of Donor-PIFE states',...
                        'Number of Acceptor-PIFE states',...
                        'Save size','Number iterations','Burn-in percentage'},...
                        'Please check the parameters',...
                        [ 1 60 ; 1 60 ; 1 60 ;1 60  ;1 60;1 60],...
                       {NF,ND,NA,'10','10000','80'},opts);
    if length(answer)==6
    while isempty(str2num(answer{1})) || str2num(answer{1})==0 ||...
          isempty(str2num(answer{2})) || str2num(answer{2})==0 ||...
          isempty(str2num(answer{3})) || str2num(answer{3})==0 ||...
          isempty(str2num(answer{4})) || str2num(answer{4})==0 

          opts.Interpreter = 'tex';
          answer = inputdlg({'Number of FRET states',...
                             'Number of Donor-PIFE states',...
                             'Number of Acceptor-PIFE states',...
                             'Save size','Number iterations','Burn-in percentage'},...
                             'Please check the parameters',...
                             [ 1 60 ; 1 60 ; 1 60 ;1 60 ;1 60 ;1 60],...
                             {NF,ND,NA,'10','10000','80'},opts);
    end

    Data.Num_states_F     = str2num(answer{1});
    Data.Num_states_D     = str2num(answer{2});
    Data.Num_states_A     = str2num(answer{3});
    Data.save_size        = str2num(answer{4});
    Max_iter              = str2num(answer{5});
    burn_in_perc          = str2num(answer{6});
    

    Data.sample_eta       = true; 

    app.FRETstatesEditField.Value         = Data.Num_states_F;
    app.acceptorPIFEstatesEditField.Value = Data.Num_states_A;
    app.donorPIFEstatesEditField.Value    = Data.Num_states_D;
    app.SavesizeEditField.Value           = Data.save_size;
    app.iterationsEditField.Value         = Max_iter;
    app.BurninEditField.Value             = burn_in_perc;


Data.KF_rate_alpha         =      1    ;
Data.KF_rate_beta          =  10000    ;

Data.F_all_alpha           =      1    ;

Data.KD_rate_alpha         =      1    ;
Data.KD_rate_beta          =  10000    ;

Data.D_all_alpha           =      1    ;

Data.KA_rate_alpha         =      1    ;
Data.KA_rate_beta          =  10000    ;

Data.A_all_alpha           =      1    ;

Data.prop_mu_D_ex          =   1000    ;
Data.alpha_mu_D_ex         =      1    ;
Data.beta_mu_D_ex          =      0.01 ;

Data.prop_mu_A_ex          =   1000    ;
Data.alpha_mu_A_ex         =      1    ;
Data.beta_mu_A_ex          =      0.01 ;

Data.prop_mu_BD            =  10000    ;
Data.alpha_mu_BD           =      1    ;
Data.beta_mu_BD            =      0.001;

Data.prop_mu_BA            =  10000    ;
Data.alpha_mu_BA           =      1    ;
Data.beta_mu_BA            =      0.001 ;

Data.sample_eta            =   true    ;
% Cross-talks

Data.eta_prop_D            =      0.5    ;
Data.alpha_eta_D           =      0.5    ;
Data.beta_eta_D            =     10    ;

Data.eta_prop_A            =      0.5    ;
Data.alpha_eta_A           =      0.5    ;
Data.beta_eta_A            =     10    ;
if  Data.sample_eta 
    Data.eta_D_learned         = betarnd(Data.alpha_eta_D , Data.beta_eta_D) ;
    Data.eta_A_learned         = betarnd(Data.alpha_eta_A , Data.beta_eta_A) ;
else % pre calibrated values
    Data.eta_D_learned     =      0.06;
    Data.eta_A_learned     =      0.06;
end

Data.KF_val_prop           =  1000       ;
Data.KF_val_alpha          =      1      ;
Data.KF_val_beta           =     10      ;

Data.KD_val_prop           =  1000       ;
Data.KD_val_alpha          =      2   ;
Data.KD_val_beta           =      4      ;

Data.KA_val_prop           =  1000       ;
Data.KA_val_alpha          =      2   ;
Data.KA_val_beta           =      4      ;


if  isfield(Data,'KD_real')
    Data.signals           = []          ; 
    
    Data.signals{1}        = Data.signal ;
end

Data.save_on_off           = true   ;

Data                       = Initiation_param(app,Data);

% Run the algorithm
Data                       = Gibbs_sampler(app, Data ,  Max_iter );


burn_in      = max([1 floor((burn_in_perc/100)*length(Data.F_all(1,:)))]);

Data_F = Data;sigg_n2_F = []; max_F(1)=0;
for mme=1:Data.num_sub_sigs
    if  mme==1
        sigg_n2_F =Data.signals{mme}(2,:); max_F(2) = max(unique(sigg_n2_F));
    else
        sigg_n2_F =[sigg_n2_F, max_F(mme)+Data.signals{mme}(2,:)]; max_F(mme+1) = max(sigg_n2_F);
    end
end


% Plot F trajectory
axInnerPos2 = app.UIAxes_3.InnerPosition; 
if isfield(Data,'F_fluc_real')
stairs(app.UIAxes_3,Data.signals{1}(2,:),Data.F_fluc_real,'g')
end
ress=[]; F_Ind_p=[]; map_F=[]; hold(app.UIAxes_3,'on')
for mmk=1:Data.num_sub_sigs
    F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
for ii=1:size(Data.F_all(1,burn_in:end),2)
    ttf(:,ii)=Data.KF_val_learned{mmk}(burn_in+ii-1,Data.F_res{mmk}(burn_in+ii-1,:));
end
    ress            = [ress,ttf']; map_F     = [map_F,Data.map_KF_val(mmk,Data.map_F_res{mmk})];
end
F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

hr11=stairs(app.UIAxes_3,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
hr22=fill(app.UIAxes_3,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
hr33=stairs(app.UIAxes_3,F_Ind_p,map_F,'-.','Color','m'); box(app.UIAxes_3,'off')
if isfield(Data,'F_fluc_real')
   F_maxx = max([1,max([Data.F_fluc_real,max(F_m_up1)])*1.1]);
else
   F_maxx = max([1,max(F_m_up1)*1.1]);
end
ylim(app.UIAxes_3,[0 F_maxx])
xlim(app.UIAxes_3,[0 max(Data.signals{1}(2,:))]);
app.UIAxes_3.XTickLabel=[];hold(app.UIAxes_3,'off')
innerPosDiff3         = axInnerPos2 - app.UIAxes_3.InnerPosition;  
app.UIAxes_3.Position = app.UIAxes_3.Position + innerPosDiff3;


% Plot Donor lifetime trajectory
axInnerPos3 = app.UIAxes_4.InnerPosition; 
if isfield(Data,'F_fluc_real')
stairs(app.UIAxes_4,Data.signals{1}(2,:),Data.tau_D_fluc_real,'g')
end
ress=[]; F_Ind_p=[]; map_D=[]; hold(app.UIAxes_4,'on')
for mmk=1:Data.num_sub_sigs
    F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
for ii=1:size(Data.D_all(1,burn_in:end),2)
    ttf(:,ii)=Data.KD_val_learned{mmk}(burn_in+ii-1,Data.tau_D_res{mmk}(burn_in+ii-1,:));
end
    ress            = [ress,ttf']; map_D     = [map_D,Data.map_KD_val(mmk,Data.map_tau_D{mmk})];
end
F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

stairs(app.UIAxes_4,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
fill(app.UIAxes_4,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
stairs(app.UIAxes_4,F_Ind_p,map_D,'-.','Color','m');box(app.UIAxes_4,'off')
if  isfield(Data,'F_fluc_real')
    D_maxx=  max([1,max(Data.tau_D_fluc_real)*1.1,max(F_m_up1)*1.1]);
else
    D_maxx=  max([1,max(F_m_up1)*1.1]);
end
ylim(app.UIAxes_4,[0 D_maxx])
xlim(app.UIAxes_4,[0 max(Data.signals{1}(2,:))]);
app.UIAxes_4.XTickLabel=[];hold(app.UIAxes_4,'off')
innerPosDiff4        = axInnerPos3 - app.UIAxes_4.InnerPosition;  
app.UIAxes_4.Position = app.UIAxes_4.Position + innerPosDiff4;


% Plot Acceptor lifetime trajectory
axInnerPos4 = app.UIAxes_5.InnerPosition; 
if isfield(Data,'F_fluc_real')
stairs(app.UIAxes_5,Data.signals{1}(2,:),Data.tau_A_fluc_real,'g')
end
ress=[]; F_Ind_p=[]; map_F=[]; hold(app.UIAxes_5,'on')
for mmk=1:Data.num_sub_sigs
    F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
for ii=1:size(Data.A_all(1,burn_in:end),2)
    ttf(:,ii)=Data.KA_val_learned{mmk}(burn_in+ii-1,Data.tau_A_res{mmk}(burn_in+ii-1,:));
end
    ress            = [ress,ttf']; map_F     = [map_F,Data.map_KA_val(mmk,Data.map_tau_A{mmk})];
end
F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

stairs(app.UIAxes_5,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
fill(app.UIAxes_5,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
stairs(app.UIAxes_5,F_Ind_p,map_F,'-.','Color','m');
if  isfield(Data,'F_fluc_real')
    A_maxx=max([1,max(Data.tau_A_fluc_real)*1.1,max(F_m_up1)*1.1]);
else
    A_maxx=max([1,max(F_m_up1)*1.1]);
end
ylim(app.UIAxes_5,[0 A_maxx]);

xlim(app.UIAxes_5,[0 max(Data.signals{1}(2,:))]);
box(app.UIAxes_5,'off') ; hold(app.UIAxes_5,'off')
innerPosDiff5         = axInnerPos4 - app.UIAxes_5.InnerPosition;  
app.UIAxes_5.Position = app.UIAxes_5.Position + innerPosDiff5;


% Plot the FRET transition rates
if Data.Num_states_F>1
edgehF=linspace(0,max(max(max(Data.KF_rate_learned(:,:,burn_in:end)))),50);
SS_F_max=0;
for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if m~=n
SS=histcounts(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),edgehF,'Normalization','pdf');
if max(SS)>SS_F_max
    SS_F_max = max(SS);
end
            end
        end
end
if  isfield(Data,'F_fluc_real')
    for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if m~=n
line(app.UIAxes_8,[1 1]*Data.KF_real(m,n),[0 1]*SS_F_max*.1,'LineStyle','-.','Color','g')
            hold(app.UIAxes_8,'on')
            end
        end
    end
end
for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if m~=n
line(app.UIAxes_8,[1 1]*Data.map_KF_rate(m,n),[0 1]*SS_F_max.*1.1,'LineStyle','-.','Color','b')
hold(app.UIAxes_8,'on')
xx1=[quantile(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
     quantile(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
fill(app.UIAxes_8,[xx1,fliplr(xx1)],[0 0 1 1]*SS_F_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
histogram(app.UIAxes_8,permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),edgehF,'Normalization','pdf');
            end
        end
end
xlim(app.UIAxes_8,[0,max(max(max(Data.KF_rate_learned(:,:,burn_in:end))))])
hold(app.UIAxes_8,'off'); box(app.UIAxes_8,'off')
end


% Plot the Acceptor_PIFE transition rates
if Data.Num_states_A>1
edgehA=linspace(0,max(max(max(Data.KA_rate_learned(:,:,burn_in:end)))),50);
SS_A_max=0;
for m=1:Data.Num_states_A
        for n=1:Data.Num_states_A
            if m~=n
SS=histcounts(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),edgehA,'Normalization','pdf');
if max(SS)>SS_A_max
    SS_A_max = max(SS);
end
            end
        end
end
if  isfield(Data,'F_fluc_real')
    for m=1:Data.Num_states_A
        for n=1:Data.Num_states_A
            if m~=n
line(app.UIAxes_7,[1 1]*Data.KA_real(m,n),[0 1]*SS_A_max*.1,'LineStyle','-.','Color','g')
           hold(app.UIAxes_7,'on')
            end
        end
    end
end
for m=1:Data.Num_states_A
        for n=1:Data.Num_states_A
            if m~=n
line(app.UIAxes_7,[1 1]*Data.map_KA_rate(m,n),[0 1]*SS_A_max.*1.1,'LineStyle','-.','Color','b')
hold(app.UIAxes_7,'on')
xx1=[quantile(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
     quantile(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
fill(app.UIAxes_7,[xx1,fliplr(xx1)],[0 0 1 1]*SS_A_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
histogram(app.UIAxes_7,permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),edgehA,'Normalization','pdf');
            end
        end
end
xlim(app.UIAxes_7,[0,max(max(max(Data.KA_rate_learned(:,:,burn_in:end))))])
hold(app.UIAxes_7,'off'); box(app.UIAxes_7,'off')
end


% Plot the Donor_PIFE transition rates
if Data.Num_states_D>1
edgehP=linspace(0,max(max(max(Data.KD_rate_learned(:,:,burn_in:end)))),50);
SS_P_max=0;
for m=1:Data.Num_states_D
        for n=1:Data.Num_states_D
            if m~=n
SS=histcounts(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),edgehP,'Normalization','pdf');
if max(SS)>SS_P_max
    SS_P_max = max(SS);
end
            end
        end
end
if  isfield(Data,'F_fluc_real')
    for m=1:Data.Num_states_D
        for n=1:Data.Num_states_D
            if m~=n
line(app.UIAxes_6,[1 1]*Data.KD_real(m,n),[0 1]*SS_P_max*1.1,'LineStyle','-.','Color','g')
            hold(app.UIAxes_6,'on')
            end
        end
    end
end
for m=1:Data.Num_states_D
        for n=1:Data.Num_states_D
            if m~=n
line(app.UIAxes_6,[1 1]*Data.map_KD_rate(m,n),[0 1]*SS_P_max.*1.1,'LineStyle','-.','Color','b')
hold(app.UIAxes_6,'on')
xx1=[quantile(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
     quantile(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
fill(app.UIAxes_6,[xx1,fliplr(xx1)],[0 0 1 1]*SS_P_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
histogram(app.UIAxes_6,permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),edgehP,'Normalization','pdf');
            end
        end
end
xlim(app.UIAxes_6,[0,max(max(max(Data.KD_rate_learned(:,:,burn_in:end))))])
hold(app.UIAxes_6,'off'); box(app.UIAxes_6,'off')
end


  end

    app.GenerateButton.Enable            ='on';
    app.ImportButton.Enable              ='on';
    app.RunButton.Enable                 ='on';
    app.HelpButton.Enable                ='on';
    app.LicenseButton.Enable             ='on';
    app.PreviouslyanalyzedfileButton.Enable='on';
    if isfield(Data,'KF_val_learned')
       app.ContinueButton.Enable         ='on';
    end
    
    end

        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            

    load([app.FilenameLabel.Text(13:end),'.mat']);  
    app.Gauge.Value=0;
    drawnow
    cla(app.UIAxes_3)
    cla(app.UIAxes_4)
    cla(app.UIAxes_5)
    cla(app.UIAxes_6)
    cla(app.UIAxes_7)
    cla(app.UIAxes_8)

    app.GenerateButton.Enable            ='off';
    app.ImportButton.Enable              ='off';
    app.RunButton.Enable                 ='off';
    app.HelpButton.Enable                ='off';
    app.LicenseButton.Enable             ='off';
    app.ContinueButton.Enable            ='off';
    app.PreviouslyanalyzedfileButton.Enable='off';

    opts.Interpreter = 'tex';
    answer = inputdlg({'Number iterations','Burn-in percentage'},...
                        'Please check the parameters',...
                        [ 1 60 ;1 60],...
                       {'10000','80'},opts);
   if length(answer)==2
    while isempty(str2num(answer{1})) || str2num(answer{1})==0 

          opts.Interpreter = 'tex';
          answer = inputdlg({'Number iterations','Burn-in percentage'},...
                        'Please check the parameters',...
                        [ 1 60  ;1 60],...
                       {'10000','80'},opts);
    end

Max_iter                         = str2num(answer{1});
app.iterationsEditField.Value    = Max_iter;

burn_in_perc                     = str2num(answer{2});
app.BurninEditField.Value        = burn_in_perc ;

pause(.05)


% Run the algorithm
Data                              = Gibbs_sampler(app , Data ,  Max_iter);


burn_in      = max([1 floor((burn_in_perc/100)*length(Data.F_all(1,:)))]);

Data_F = Data;sigg_n2_F = []; max_F(1)=0;
for mme=1:Data.num_sub_sigs
    if  mme==1
        sigg_n2_F =Data.signals{mme}(2,:); max_F(2) = max(unique(sigg_n2_F));
    else
        sigg_n2_F =[sigg_n2_F, max_F(mme)+Data.signals{mme}(2,:)]; max_F(mme+1) = max(sigg_n2_F);
    end
end


% Plot F trajectory
axInnerPos2 = app.UIAxes_3.InnerPosition; 
if  isfield(Data,'F_fluc_real')
    stairs(app.UIAxes_3,Data.signals{1}(2,:),Data.F_fluc_real,'g')
end
ress=[]; F_Ind_p=[]; map_F=[]; hold(app.UIAxes_3,'on')
for mmk=1:Data.num_sub_sigs
    F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
for ii=1:size(Data.F_all(1,burn_in:end),2)
    ttf(:,ii)=Data.KF_val_learned{mmk}(burn_in+ii-1,Data.F_res{mmk}(burn_in+ii-1,:));
end
    ress            = [ress,ttf']; map_F     = [map_F,Data.map_KF_val(mmk,Data.map_F_res{mmk})];
end
F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

hr11=stairs(app.UIAxes_3,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
hr22=fill(app.UIAxes_3,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
hr33=stairs(app.UIAxes_3,F_Ind_p,map_F,'-.','Color','m');
if  isfield(Data,'F_fluc_real')
    F_maxx = max([1,max([Data.F_fluc_real,max(F_m_up1)])*1.1]);
else
    F_maxx = max([1,max(F_m_up1)*1.1]);
end
ylim(app.UIAxes_3,[0 F_maxx])
xlim(app.UIAxes_3,[0 max(Data.signals{1}(2,:))]);
app.UIAxes_3.XTickLabel=[];box(app.UIAxes_3,'off'); hold(app.UIAxes_3,'off')
innerPosDiff3        = axInnerPos2 - app.UIAxes_3.InnerPosition;  
app.UIAxes_3.Position = app.UIAxes_3.Position + innerPosDiff3;


% Plot Donor lifetime trajectory
axInnerPos3 = app.UIAxes_4.InnerPosition; 
if isfield(Data,'F_fluc_real')
stairs(app.UIAxes_4,Data.signals{1}(2,:),Data.tau_D_fluc_real,'g')
end
ress=[]; F_Ind_p=[]; map_F=[]; hold(app.UIAxes_4,'on')
for mmk=1:Data.num_sub_sigs
    F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
for ii=1:size(Data.D_all(1,burn_in:end),2)
    ttf(:,ii)=Data.KD_val_learned{mmk}(burn_in+ii-1,Data.tau_D_res{mmk}(burn_in+ii-1,:));
end
    ress            = [ress,ttf']; map_F     = [map_F,Data.map_KD_val(mmk,Data.map_tau_D{mmk})];
end
F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

stairs(app.UIAxes_4,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
fill(app.UIAxes_4,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
stairs(app.UIAxes_4,F_Ind_p,map_F,'-.','Color','m');
if  isfield(Data,'F_fluc_real')
    D_maxx=  max([1,max(Data.tau_D_fluc_real)*1.1,max(F_m_up1)*1.1]);
else
    D_maxx=  max([1,max(F_m_up1)*1.1]);
end
ylim(app.UIAxes_4,[0 D_maxx])
xlim(app.UIAxes_4,[0 max(Data.signals{1}(2,:))]);
app.UIAxes_4.XTickLabel=[];box(app.UIAxes_4,'off'); hold(app.UIAxes_4,'off')
innerPosDiff4        = axInnerPos3 - app.UIAxes_4.InnerPosition;  
app.UIAxes_4.Position = app.UIAxes_4.Position + innerPosDiff4;


% Plot Acceptor lifetime trajectory
axInnerPos4 = app.UIAxes_5.InnerPosition; 
if isfield(Data,'F_fluc_real')
stairs(app.UIAxes_5,Data.signals{1}(2,:),Data.tau_A_fluc_real,'g')
end
ress=[]; F_Ind_p=[]; map_F=[]; hold(app.UIAxes_5,'on')
for mmk=1:Data.num_sub_sigs
    F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
for ii=1:size(Data.A_all(1,burn_in:end),2)
    ttf(:,ii)=Data.KA_val_learned{mmk}(burn_in+ii-1,Data.tau_A_res{mmk}(burn_in+ii-1,:));
end
    ress            = [ress,ttf']; map_F     = [map_F,Data.map_KA_val(mmk,Data.map_tau_A{mmk})];
end
F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

stairs(app.UIAxes_5,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
fill(app.UIAxes_5,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
stairs(app.UIAxes_5,F_Ind_p,map_F,'-.','Color','m');
if  isfield(Data,'F_fluc_real')
    A_maxx=max([1,max(Data.tau_A_fluc_real)*1.1,max(F_m_up1)*1.1]);
else
    A_maxx=max([1,max(F_m_up1)*1.1]);
end
ylim(app.UIAxes_5,[0 A_maxx]);

xlim(app.UIAxes_5,[0 max(Data.signals{1}(2,:))]);
box(app.UIAxes_5,'off'); hold(app.UIAxes_5,'off')
innerPosDiff5         = axInnerPos4 - app.UIAxes_5.InnerPosition;  
app.UIAxes_5.Position = app.UIAxes_5.Position + innerPosDiff5;


% Plot the FRET transition rates
if Data.Num_states_F>1
edgehF=linspace(0,max(max(max(Data.KF_rate_learned(:,:,burn_in:end)))),50);
SS_F_max=0;
for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if m~=n
SS=histcounts(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),edgehF,'Normalization','pdf');
if max(SS)>SS_F_max
    SS_F_max = max(SS);
end
            end
        end
end
if  isfield(Data,'F_fluc_real')
    for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if m~=n
line(app.UIAxes_8,[1 1]*Data.KF_real(m,n),[0 1]*SS_F_max*1.1,'LineStyle','-.','Color','g')
            hold(app.UIAxes_8,'on')
            end
        end
    end
end
for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if m~=n
line(app.UIAxes_8,[1 1]*Data.map_KF_rate(m,n),[0 1]*SS_F_max.*1.1,'LineStyle','-.','Color','b')
hold(app.UIAxes_8,'on')
xx1=[quantile(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
     quantile(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
fill(app.UIAxes_8,[xx1,fliplr(xx1)],[0 0 1 1]*SS_F_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
histogram(app.UIAxes_8,permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),edgehF,'Normalization','pdf');
            end
        end
end
xlim(app.UIAxes_8,[0,max(max(max(Data.KF_rate_learned(:,:,burn_in:end))))])
hold(app.UIAxes_8,'off'); box(app.UIAxes_8,'off')
end


% Plot the Acceptor_PIFE transition rates
if Data.Num_states_A>1
edgehA=linspace(0,max(max(max(Data.KA_rate_learned(:,:,burn_in:end)))),50);
SS_A_max=0;
for m=1:Data.Num_states_A
        for n=1:Data.Num_states_A
            if m~=n
SS=histcounts(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),edgehA,'Normalization','pdf');
if max(SS)>SS_A_max
    SS_A_max = max(SS);
end
            end
        end
end
if  isfield(Data,'F_fluc_real')
    for m=1:Data.Num_states_A
        for n=1:Data.Num_states_A
            if m~=n
line(app.UIAxes_7,[1 1]*Data.KA_real(m,n),[0 1]*SS_A_max*1.1,'LineStyle','-.','Color','g')
            hold(app.UIAxes_7,'on')
            end
        end
    end
end
for m=1:Data.Num_states_A
        for n=1:Data.Num_states_A
            if m~=n
line(app.UIAxes_7,[1 1]*Data.map_KA_rate(m,n),[0 1]*SS_A_max.*1.1,'LineStyle','-.','Color','b')
hold(app.UIAxes_7,'on')
xx1=[quantile(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
     quantile(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
fill(app.UIAxes_7,[xx1,fliplr(xx1)],[0 0 1 1]*SS_A_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
histogram(app.UIAxes_7,permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),edgehA,'Normalization','pdf');
            end
        end
end
xlim(app.UIAxes_7,[0,max(max(max(Data.KA_rate_learned(:,:,burn_in:end))))])
hold(app.UIAxes_7,'off'); box(app.UIAxes_7,'off')
end


% Plot the Donor_PIFE transition rates
if Data.Num_states_D>1
edgehP=linspace(0,max(max(max(Data.KD_rate_learned(:,:,burn_in:end)))),50);
SS_P_max=0;
for m=1:Data.Num_states_D
        for n=1:Data.Num_states_D
            if m~=n
SS=histcounts(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),edgehP,'Normalization','pdf');
if max(SS)>SS_P_max
    SS_P_max = max(SS);
end
            end
        end
end
if  isfield(Data,'F_fluc_real')
    for m=1:Data.Num_states_D
        for n=1:Data.Num_states_D
            if m~=n
line(app.UIAxes_6,[1 1]*Data.KD_real(m,n),[0 1]*SS_P_max*1.1,'LineStyle','-.','Color','g')
            hold(app.UIAxes_6,'on')
            end
        end
    end
end
    for m=1:Data.Num_states_D
        for n=1:Data.Num_states_D
            if m~=n
               line(app.UIAxes_6,[1 1]*Data.map_KD_rate(m,n),[0 1]*SS_P_max.*1.1,'LineStyle','-.','Color','b')
               hold(app.UIAxes_6,'on')
               xx1=[quantile(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
                    quantile(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
               fill(app.UIAxes_6,[xx1,fliplr(xx1)],[0 0 1 1]*SS_P_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
               histogram(app.UIAxes_6,permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),edgehP,'Normalization','pdf');
            end
        end
    end
    xlim(app.UIAxes_6,[0,max(max(max(Data.KD_rate_learned(:,:,burn_in:end))))])
    hold(app.UIAxes_6,'off'); box(app.UIAxes_6,'off')
   end
   end


    app.PreviouslyanalyzedfileButton.Enable='on';
    app.GenerateButton.Enable            ='on';
    app.ImportButton.Enable              ='on';
    app.RunButton.Enable                 ='on';
    app.HelpButton.Enable                ='on';
    app.LicenseButton.Enable             ='on';
    app.ContinueButton.Enable            ='on';

        end

        % Button pushed function: LicenseButton
        function LicenseButtonPushed(app, event)
            close all
            hFig = figure('Menubar','none', 'Toolbar','none',...
                          'numbertitle','off','name','FL-smFRET-smPIFE v1.1 README');
            set(hFig,'Units','Characters','Position',get(hFig,'Position').*[1 1 0 0.12]+[0 0 80 0]);
            uicontrol(hFig, 'Style','edit',...
            'Min',0, 'Max',2, 'HorizontalAlignment','left', ...
            'Units','normalized', 'Position',[0 0 1 1], ...
            'String',fileread('README.txt'));
            movegui(hFig,'center');

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            close all
            figure('Name','Help','Units','inch','Position',[0 0 8 11])
            set(gcf,'color','w') ; clf ; imshow('Help.jpg'); movegui(gcf,'center')
        end

        % Button pushed function: PreviouslyanalyzedfileButton
        function PreviouslyanalyzedfileButtonPushed(app, event)
            
 clc


% Find the file from the directory
% commandwindow();
    [filename, pathname] = uigetfile({'*.mat','MAT-files (*.mat)'},'Import a time series','MultiSelect', 'off');
    
    % Check the filename 
    if ~isequal(filename,0)
       addpath(pathname)
       % Load the file
       load(fullfile(pathname,filename))

    app.FilenameLabel.Text = ['File name : ',filename(1:end-4)] ;

    app.Gauge.Value=0;
    drawnow
    app.PreviouslyanalyzedfileButton.Enable= 'off';
    app.GenerateButton.Enable              = 'off';
    app.ImportButton.Enable                = 'off';
    app.RunButton.Enable                   = 'off';           
    app.HelpButton.Enable                  = 'off';
    app.LicenseButton.Enable               = 'off';
    app.FRETstatesEditField.Enable         = 'off';
    app.acceptorPIFEstatesEditField.Enable = 'off';
    app.donorPIFEstatesEditField.Enable    = 'off';
    app.ContinueButton.Enable              = 'off';


    app.PulseperiodnsEditField.Value      = Data.pulse_priod ;
    app.mu_textIRFDnsEditField.Value      = Data.IRF_mean(1);
    app.sigma_textIRFDnsEditField.Value   = Data.IRF_str(1) ;
    app.mu_textIRFAnsEditField.Value      = Data.IRF_mean(2);
    app.sigma_textIRFAnsEditField.Value   = Data.IRF_str(2);

    
    if  isfield(Data,'KD_real')
        Data.signals                          = []          ;
        Data.signals{1}                       = Data.signal ;
        app.mu_textbackDphtssEditField.Value  = num2str(Data.Back_D_real);
        app.mu_textbackAphtssEditField.Value  = num2str(Data.Back_A_real);
        app.mu_textexDexcpulseEditField.Value = ['[',num2str(Data.ex_D_real)  ,']'];
        app.mu_textexAexcpulseEditField.Value = ['[',num2str(Data.ex_A_real)  ,']'];
        app.tau_textDnsEditField.Value        = ['[',num2str(Data.KD_val_real),']'];
        app.tau_textAnsEditField.Value        = ['[',num2str(Data.KA_val_real),']'];
        app.K_textFRETns1EditField.Value      = ['[',num2str(Data.KF_val_real),']'];
        app.FRETstatesEditField.Value         = length(Data.KF_val_real);
        app.acceptorPIFEstatesEditField.Value = length(Data.KA_val_real);
        app.donorPIFEstatesEditField.Value    = length(Data.KD_val_real);
        app.overlineoverlineQ_DEditField.Value= ['[',num2str(reshape(Data.KD_real,1, prod(size(Data.KD_real,[1,2])))),']'];
        app.overlineoverlineQ_AEditField.Value= ['[',num2str(reshape(Data.KA_real,1, prod(size(Data.KA_real,[1,2])))),']'] ;
        app.overlineoverlineQ_FEditField.Value= ['[',num2str(reshape(Data.KF_real,1, prod(size(Data.KF_real,[1,2])))),']'] ;
        app.eta_textDEditField.Value          = Data.eta_D_real;
        app.eta_textAEditField.Value          = Data.eta_A_real;
    else
        app.mu_textbackDphtssEditField.Value  = '' ;
        app.mu_textbackAphtssEditField.Value  = '' ;
        app.mu_textexDexcpulseEditField.Value = '' ;
        app.mu_textexAexcpulseEditField.Value = '' ;
        app.tau_textDnsEditField.Value        = '' ;
        app.tau_textAnsEditField.Value        = '' ;
        app.K_textFRETns1EditField.Value      = '' ;
        app.FRETstatesEditField.Value         = 1  ;
        app.acceptorPIFEstatesEditField.Value = 1  ;
        app.donorPIFEstatesEditField.Value    = 1  ;
        app.overlineoverlineQ_DEditField.Value= [] ;
        app.overlineoverlineQ_AEditField.Value= [] ;
        app.overlineoverlineQ_FEditField.Value= [] ;
        app.eta_textDEditField.Value          = 0  ;
        app.eta_textAEditField.Value          = 0  ;
    end

    app.LengthsEditField.Value            = Data.signals{1}(2,end);
    app.NumberphotonsEditField.Value      = size(Data.signals{1},2);
    cla(app.UIAxes)
    cla(app.UIAxes_2)
    cla(app.UIAxes_3)
    cla(app.UIAxes_4)
    cla(app.UIAxes_5)
    cla(app.UIAxes_6)
    cla(app.UIAxes_7)
    cla(app.UIAxes_8)

axInnerPos = app.UIAxes.InnerPosition;  
scatter(app.UIAxes,Data.signals{1}(2,Data.signals{1}(3,:)==1),Data.signals{1}(1,Data.signals{1}(3,:)==1),1,'MarkerEdgeColor','none','MarkerFaceColor','g')
ylim(app.UIAxes,[0 Data.pulse_priod])
xlim(app.UIAxes,[0 max(Data.signals{1}(2,:))]);
app.UIAxes.XTickLabel=[];box(app.UIAxes,'off')
innerPosDiff1        = axInnerPos - app.UIAxes.InnerPosition;  
app.UIAxes.Position = app.UIAxes.Position + innerPosDiff1;

axInnerPos1 = app.UIAxes_2.InnerPosition; 
scatter(app.UIAxes_2,Data.signals{1}(2,Data.signals{1}(3,:)==2),Data.signals{1}(1,Data.signals{1}(3,:)==2),1,'MarkerEdgeColor','none','MarkerFaceColor','r')
ylim(app.UIAxes_2,[0 Data.pulse_priod])
app.UIAxes_2.YDir = 'reverse'; app.UIAxes_2.XAxisLocation ='origin';box(app.UIAxes_2,'off')
xlim(app.UIAxes_2,[0 max(Data.signals{1}(2,:))]);
app.UIAxes_2.XTickLabel=[];
innerPosDiff2            = axInnerPos1 - app.UIAxes_2.InnerPosition;  
app.UIAxes_2.Position   = app.UIAxes_2.Position + innerPosDiff2;
app.UIAxes_2.Position(2)=app.UIAxes.Position(2)-app.UIAxes_2.Position(4)+12;
    

if isfield(Data,'F_res')

burn_in_perc = 60 ; 
burn_in      = max([1 floor((burn_in_perc/100)*length(Data.F_all(1,:)))]);

Data_F = Data;sigg_n2_F = []; max_F(1)=0;
for mme=1:Data.num_sub_sigs
    if  mme==1
        sigg_n2_F =Data.signals{mme}(2,:); max_F(2) = max(unique(sigg_n2_F));
    else
        sigg_n2_F =[sigg_n2_F, max_F(mme)+Data.signals{mme}(2,:)]; max_F(mme+1) = max(sigg_n2_F);
    end
end


% Plot F trajectory
axInnerPos2 = app.UIAxes_3.InnerPosition; 
if isfield(Data,'F_fluc_real')
stairs(app.UIAxes_3,Data.signals{1}(2,:),Data.F_fluc_real,'g')
end
ress=[]; F_Ind_p=[]; map_F=[]; hold(app.UIAxes_3,'on')
for mmk=1:Data.num_sub_sigs
    F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
for ii=1:size(Data.F_all(1,burn_in:end),2)
    ttf(:,ii)=Data.KF_val_learned{mmk}(burn_in+ii-1,Data.F_res{mmk}(burn_in+ii-1,:));
end
    ress            = [ress,ttf']; map_F     = [map_F,Data.map_KF_val(mmk,Data.map_F_res{mmk})];
end
F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

hr11=stairs(app.UIAxes_3,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
hr22=fill(app.UIAxes_3,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
hr33=stairs(app.UIAxes_3,F_Ind_p,map_F,'-.','Color','m'); box(app.UIAxes_3,'off')
if isfield(Data,'F_fluc_real')
   F_maxx = max([1,max([Data.F_fluc_real,max(F_m_up1)])*1.1]);
else
   F_maxx = max([1,max(F_m_up1)*1.1]);
end
ylim(app.UIAxes_3,[0 F_maxx])
xlim(app.UIAxes_3,[0 max(Data.signals{1}(2,:))]);
app.UIAxes_3.XTickLabel=[];hold(app.UIAxes_3,'off')
innerPosDiff3         = axInnerPos2 - app.UIAxes_3.InnerPosition;  
app.UIAxes_3.Position = app.UIAxes_3.Position + innerPosDiff3;


% Plot Donor lifetime trajectory
axInnerPos3 = app.UIAxes_4.InnerPosition; 
if isfield(Data,'F_fluc_real')
stairs(app.UIAxes_4,Data.signals{1}(2,:),Data.tau_D_fluc_real,'g')
end
ress=[]; F_Ind_p=[]; map_D=[]; hold(app.UIAxes_4,'on')
for mmk=1:Data.num_sub_sigs
    F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
for ii=1:size(Data.D_all(1,burn_in:end),2)
    ttf(:,ii)=Data.KD_val_learned{mmk}(burn_in+ii-1,Data.tau_D_res{mmk}(burn_in+ii-1,:));
end
    ress            = [ress,ttf']; map_D     = [map_D,Data.map_KD_val(mmk,Data.map_tau_D{mmk})];
end
F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

stairs(app.UIAxes_4,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
fill(app.UIAxes_4,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
stairs(app.UIAxes_4,F_Ind_p,map_D,'-.','Color','m');box(app.UIAxes_4,'off')
if  isfield(Data,'F_fluc_real')
    D_maxx=  max([1,max(Data.tau_D_fluc_real)*1.1,max(F_m_up1)*1.1]);
else
    D_maxx=  max([1,max(F_m_up1)*1.1]);
end
ylim(app.UIAxes_4,[0 D_maxx])
xlim(app.UIAxes_4,[0 max(Data.signals{1}(2,:))]);
app.UIAxes_4.XTickLabel=[];hold(app.UIAxes_4,'off')
innerPosDiff4        = axInnerPos3 - app.UIAxes_4.InnerPosition;  
app.UIAxes_4.Position = app.UIAxes_4.Position + innerPosDiff4;


% Plot Acceptor lifetime trajectory
axInnerPos4 = app.UIAxes_5.InnerPosition; 
if isfield(Data,'F_fluc_real')
stairs(app.UIAxes_5,Data.signals{1}(2,:),Data.tau_A_fluc_real,'g')
end
ress=[]; F_Ind_p=[]; map_F=[]; hold(app.UIAxes_5,'on')
for mmk=1:Data.num_sub_sigs
    F_Ind_p    = [F_Ind_p,max_F(mmk)+Data.signals{mmk}(2,:)]; ttf=[];
for ii=1:size(Data.A_all(1,burn_in:end),2)
    ttf(:,ii)=Data.KA_val_learned{mmk}(burn_in+ii-1,Data.tau_A_res{mmk}(burn_in+ii-1,:));
end
    ress            = [ress,ttf']; map_F     = [map_F,Data.map_KA_val(mmk,Data.map_tau_A{mmk})];
end
F_m_down1 = quantile(ress , 0.025); F_m_up1   = quantile(ress , 0.975); F_m  = quantile(ress , 0.5);

stairs(app.UIAxes_5,F_Ind_p,F_m,'b'); xx = [F_Ind_p , fliplr(F_Ind_p)];
fill(app.UIAxes_5,xx,[F_m_down1 , fliplr(F_m_up1)], 'c','facealpha', 0.2,'edgecolor', 'none'); 
stairs(app.UIAxes_5,F_Ind_p,map_F,'-.','Color','m');
if  isfield(Data,'F_fluc_real')
    A_maxx=max([1,max(Data.tau_A_fluc_real)*1.1,max(F_m_up1)*1.1]);
else
    A_maxx=max([1,max(F_m_up1)*1.1]);
end
ylim(app.UIAxes_5,[0 A_maxx]);

xlim(app.UIAxes_5,[0 max(Data.signals{1}(2,:))]);
box(app.UIAxes_5,'off') ; hold(app.UIAxes_5,'off')
innerPosDiff5         = axInnerPos4 - app.UIAxes_5.InnerPosition;  
app.UIAxes_5.Position = app.UIAxes_5.Position + innerPosDiff5;


% Plot the FRET transition rates
if Data.Num_states_F>1
edgehF=linspace(0,max(max(max(Data.KF_rate_learned(:,:,burn_in:end)))),50);
SS_F_max=0;
for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if m~=n
SS=histcounts(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),edgehF,'Normalization','pdf');
if max(SS)>SS_F_max
    SS_F_max = max(SS);
end
            end
        end
end
if  isfield(Data,'F_fluc_real')
    for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if m~=n
line(app.UIAxes_8,[1 1]*Data.KF_real(m,n),[0 1]*SS_F_max*.1,'LineStyle','-.','Color','g')
            hold(app.UIAxes_8,'on')
            end
        end
    end
end
for m=1:Data.Num_states_F
        for n=1:Data.Num_states_F
            if m~=n
line(app.UIAxes_8,[1 1]*Data.map_KF_rate(m,n),[0 1]*SS_F_max.*1.1,'LineStyle','-.','Color','b')
hold(app.UIAxes_8,'on')
xx1=[quantile(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
     quantile(permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
fill(app.UIAxes_8,[xx1,fliplr(xx1)],[0 0 1 1]*SS_F_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
histogram(app.UIAxes_8,permute(Data.KF_rate_learned(m,n,burn_in:end),[1,3,2]),edgehF,'Normalization','pdf');
            end
        end
end
xlim(app.UIAxes_8,[0,max(max(max(Data.KF_rate_learned(:,:,burn_in:end))))])
hold(app.UIAxes_8,'off'); box(app.UIAxes_8,'off')
end


% Plot the Acceptor_PIFE transition rates
if Data.Num_states_A>1
edgehA=linspace(0,max(max(max(Data.KA_rate_learned(:,:,burn_in:end)))),50);
SS_A_max=0;
for m=1:Data.Num_states_A
        for n=1:Data.Num_states_A
            if m~=n
SS=histcounts(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),edgehA,'Normalization','pdf');
if max(SS)>SS_A_max
    SS_A_max = max(SS);
end
            end
        end
end
if  isfield(Data,'F_fluc_real')
    for m=1:Data.Num_states_A
        for n=1:Data.Num_states_A
            if m~=n
line(app.UIAxes_7,[1 1]*Data.KA_real(m,n),[0 1]*SS_A_max*.1,'LineStyle','-.','Color','g')
           hold(app.UIAxes_7,'on')
            end
        end
    end
end
for m=1:Data.Num_states_A
        for n=1:Data.Num_states_A
            if m~=n
line(app.UIAxes_7,[1 1]*Data.map_KA_rate(m,n),[0 1]*SS_A_max.*1.1,'LineStyle','-.','Color','b')
hold(app.UIAxes_7,'on')
xx1=[quantile(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
     quantile(permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
fill(app.UIAxes_7,[xx1,fliplr(xx1)],[0 0 1 1]*SS_A_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
histogram(app.UIAxes_7,permute(Data.KA_rate_learned(m,n,burn_in:end),[1,3,2]),edgehA,'Normalization','pdf');
            end
        end
end
xlim(app.UIAxes_7,[0,max(max(max(Data.KA_rate_learned(:,:,burn_in:end))))])
hold(app.UIAxes_7,'off'); box(app.UIAxes_7,'off')
end


% Plot the Donor_PIFE transition rates
if Data.Num_states_D>1
edgehP=linspace(0,max(max(max(Data.KD_rate_learned(:,:,burn_in:end)))),50);
SS_P_max=0;
for m=1:Data.Num_states_D
        for n=1:Data.Num_states_D
            if m~=n
SS=histcounts(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),edgehP,'Normalization','pdf');
if max(SS)>SS_P_max
    SS_P_max = max(SS);
end
            end
        end
end
if  isfield(Data,'F_fluc_real')
    for m=1:Data.Num_states_D
        for n=1:Data.Num_states_D
            if m~=n
line(app.UIAxes_6,[1 1]*Data.KD_real(m,n),[0 1]*SS_P_max*1.1,'LineStyle','-.','Color','g')
            hold(app.UIAxes_6,'on')
            end
        end
    end
end
for m=1:Data.Num_states_D
        for n=1:Data.Num_states_D
            if m~=n
line(app.UIAxes_6,[1 1]*Data.map_KD_rate(m,n),[0 1]*SS_P_max.*1.1,'LineStyle','-.','Color','b')
hold(app.UIAxes_6,'on')
xx1=[quantile(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),0.025),...
     quantile(permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),0.975)];
fill(app.UIAxes_6,[xx1,fliplr(xx1)],[0 0 1 1]*SS_P_max.*1.1,'c','facealpha', 0.2,'edgecolor', 'none');
histogram(app.UIAxes_6,permute(Data.KD_rate_learned(m,n,burn_in:end),[1,3,2]),edgehP,'Normalization','pdf');
            end
        end
end
xlim(app.UIAxes_6,[0,max(max(max(Data.KD_rate_learned(:,:,burn_in:end))))])
hold(app.UIAxes_6,'off'); box(app.UIAxes_6,'off')
end


end
    
    end
    if isfield(Data,'KF_val_learned')
       app.ContinueButton.Enable            ='on';
    end

    app.GenerateButton.Enable               ='on';
    app.ImportButton.Enable                 ='on';
    app.HelpButton.Enable                   ='on';
    app.LicenseButton.Enable                ='on';
    app.RunButton.Enable                    ='on';
    app.PreviouslyanalyzedfileButton.Enable ='on';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            ylabel(app.UIAxes, '.')
            app.UIAxes.XTickLabel = '';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_2, '                 Photon arrival time (ns)')
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_3, 'K_{FRET} (ns^{-1})')
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_4, '\tau_D (ns)')
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_5, 'Time (s)')
            ylabel(app.UIAxes_5, '\tau_A (ns)')
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.KnownparametersPanel = uipanel(app.UIFigure);
            app.KnownparametersPanel.Title = 'Known parameters';
            app.PulseperiodnsEditFieldLabel = uilabel(app.KnownparametersPanel);
            app.PulseperiodnsEditFieldLabel.Enable = 'off';
            app.PulseperiodnsEditFieldLabel.Text = 'Pulse period (ns)';
            app.PulseperiodnsEditField = uieditfield(app.KnownparametersPanel, 'numeric');
            app.PulseperiodnsEditField.Limits = [0 Inf];
            app.PulseperiodnsEditField.Enable = 'off';
            app.mu_IRFDLabel = uilabel(app.KnownparametersPanel);
            app.mu_IRFDLabel.Interpreter = 'latex';
            app.mu_IRFDLabel.Enable = 'off';
            app.mu_IRFDLabel.Text = '$\mu_{\text{IRF,D}}$ (ns)';
            app.mu_textIRFDnsEditField = uieditfield(app.KnownparametersPanel, 'numeric');
            app.mu_textIRFDnsEditField.Limits = [0 Inf];
            app.mu_textIRFDnsEditField.Enable = 'off';
            app.sigma_textIRFDLabel = uilabel(app.KnownparametersPanel);
            app.sigma_textIRFDLabel.Interpreter = 'latex';
            app.sigma_textIRFDLabel.Enable = 'off';
            app.sigma_textIRFDLabel.Text = '$\sigma_{\text{IRF,D}}$ (ns)';
            app.sigma_textIRFDnsEditField = uieditfield(app.KnownparametersPanel, 'numeric');
            app.sigma_textIRFDnsEditField.Limits = [0 Inf];
            app.sigma_textIRFDnsEditField.Enable = 'off';
            app.mu_textIRFAnsEditFieldLabel = uilabel(app.KnownparametersPanel);
            app.mu_textIRFAnsEditFieldLabel.Interpreter = 'latex';
            app.mu_textIRFAnsEditFieldLabel.Enable = 'off';
            app.mu_textIRFAnsEditFieldLabel.Text = '$\mu_{\text{IRF,A}}$ (ns)';
            app.mu_textIRFAnsEditField = uieditfield(app.KnownparametersPanel, 'numeric');
            app.mu_textIRFAnsEditField.Limits = [0 Inf];
            app.mu_textIRFAnsEditField.Enable = 'off';
            app.sigma_textIRFAnsEditFieldLabel = uilabel(app.KnownparametersPanel);
            app.sigma_textIRFAnsEditFieldLabel.Interpreter = 'latex';
            app.sigma_textIRFAnsEditFieldLabel.Enable = 'off';
            app.sigma_textIRFAnsEditFieldLabel.Text = '$\sigma_{\text{IRF,A}}$ (ns)';
            app.sigma_textIRFAnsEditField = uieditfield(app.KnownparametersPanel, 'numeric');
            app.sigma_textIRFAnsEditField.Limits = [0 Inf];
            app.sigma_textIRFAnsEditField.Enable = 'off';
            app.LengthsEditFieldLabel = uilabel(app.KnownparametersPanel);
            app.LengthsEditFieldLabel.Enable = 'off';
            app.LengthsEditFieldLabel.Text = 'Length (s)';
            app.LengthsEditField = uieditfield(app.KnownparametersPanel, 'numeric');
            app.LengthsEditField.Limits = [0 Inf];
            app.LengthsEditField.Enable = 'off';
            app.NumberphotonsEditFieldLabel = uilabel(app.KnownparametersPanel);
            app.NumberphotonsEditFieldLabel.Enable = 'off';
            app.NumberphotonsEditFieldLabel.Text = 'Number photons';
            app.NumberphotonsEditField = uieditfield(app.KnownparametersPanel, 'numeric');
            app.NumberphotonsEditField.Limits = [0 Inf];
            app.NumberphotonsEditField.Enable = 'off';
            app.SyntheticdatasetparametersPanel = uipanel(app.UIFigure);
            app.SyntheticdatasetparametersPanel.Title = 'Synthetic data set parameters';
            app.eta_textDEditFieldLabel = uilabel(app.SyntheticdatasetparametersPanel);
            app.eta_textDEditFieldLabel.Interpreter = 'latex';
            app.eta_textDEditFieldLabel.Enable = 'off';
            app.eta_textDEditFieldLabel.Text = '$\eta_{\text{D}}$';
            app.eta_textDEditField = uieditfield(app.SyntheticdatasetparametersPanel, 'numeric');
            app.eta_textDEditField.Limits = [0 1];
            app.eta_textDEditField.Enable = 'off';
            app.eta_textAEditFieldLabel = uilabel(app.SyntheticdatasetparametersPanel);
            app.eta_textAEditFieldLabel.Interpreter = 'latex';
            app.eta_textAEditFieldLabel.Enable = 'off';
            app.eta_textAEditFieldLabel.Text = '$\eta_{\text{A}}$';
            app.eta_textAEditField = uieditfield(app.SyntheticdatasetparametersPanel, 'numeric');
            app.eta_textAEditField.Limits = [0 1];
            app.eta_textAEditField.Enable = 'off';
            app.tau_textALabel = uilabel(app.SyntheticdatasetparametersPanel);
            app.tau_textALabel.Interpreter = 'latex';
            app.tau_textALabel.Enable = 'off';
            app.tau_textALabel.Text = '$\tau_{\text{A}}$ (ns)';
            app.tau_textAnsEditField = uieditfield(app.SyntheticdatasetparametersPanel, 'text');
            app.tau_textAnsEditField.Enable = 'off';
            app.tau_textDnsEditFieldLabel = uilabel(app.SyntheticdatasetparametersPanel);
            app.tau_textDnsEditFieldLabel.Interpreter = 'latex';
            app.tau_textDnsEditFieldLabel.Enable = 'off';
            app.tau_textDnsEditFieldLabel.Text = '$\tau_{\text{D}}$ (ns)';
            app.tau_textDnsEditField = uieditfield(app.SyntheticdatasetparametersPanel, 'text');
            app.tau_textDnsEditField.Enable = 'off';
            app.K_textFRETns1EditFieldLabel = uilabel(app.SyntheticdatasetparametersPanel);
            app.K_textFRETns1EditFieldLabel.Interpreter = 'latex';
            app.K_textFRETns1EditFieldLabel.Enable = 'off';
            app.K_textFRETns1EditFieldLabel.Text = 'K$_{\text{FRET}}$ (ns$^{-1}$)';
            app.K_textFRETns1EditField = uieditfield(app.SyntheticdatasetparametersPanel, 'text');
            app.K_textFRETns1EditField.Enable = 'off';
            app.mu_textexAexcpulseEditFieldLabel = uilabel(app.SyntheticdatasetparametersPanel);
            app.mu_textexAexcpulseEditFieldLabel.Interpreter = 'latex';
            app.mu_textexAexcpulseEditFieldLabel.Enable = 'off';
            app.mu_textexAexcpulseEditFieldLabel.Text = '$\mu_{\text{ex,A}}$ (exc/pulse)';
            app.mu_textexAexcpulseEditField = uieditfield(app.SyntheticdatasetparametersPanel, 'text');
            app.mu_textexAexcpulseEditField.Enable = 'off';
            app.mu_textexDexcpulseEditFieldLabel = uilabel(app.SyntheticdatasetparametersPanel);
            app.mu_textexDexcpulseEditFieldLabel.Interpreter = 'latex';
            app.mu_textexDexcpulseEditFieldLabel.Enable = 'off';
            app.mu_textexDexcpulseEditFieldLabel.Text = '$\mu_{\text{ex,D}}$ (exc/pulse)';
            app.mu_textexDexcpulseEditField = uieditfield(app.SyntheticdatasetparametersPanel, 'text');
            app.mu_textexDexcpulseEditField.Enable = 'off';
            app.mu_textbackDphtssEditFieldLabel = uilabel(app.SyntheticdatasetparametersPanel);
            app.mu_textbackDphtssEditFieldLabel.Interpreter = 'latex';
            app.mu_textbackDphtssEditFieldLabel.Enable = 'off';
            app.mu_textbackDphtssEditFieldLabel.Text = '$\mu_{\text{back,D}}$ (phts/s)';
            app.mu_textbackDphtssEditField = uieditfield(app.SyntheticdatasetparametersPanel, 'text');
            app.mu_textbackDphtssEditField.Enable = 'off';
            app.mu_textbackAphtssEditFieldLabel = uilabel(app.SyntheticdatasetparametersPanel);
            app.mu_textbackAphtssEditFieldLabel.Interpreter = 'latex';
            app.mu_textbackAphtssEditFieldLabel.Enable = 'off';
            app.mu_textbackAphtssEditFieldLabel.Text = '$\mu_{\text{back,A}}$ (phts/s)';
            app.mu_textbackAphtssEditField = uieditfield(app.SyntheticdatasetparametersPanel, 'text');
            app.mu_textbackAphtssEditField.Enable = 'off';
            app.overlineoverlineQ_FEditFieldLabel = uilabel(app.SyntheticdatasetparametersPanel);
            app.overlineoverlineQ_FEditFieldLabel.Interpreter = 'latex';
            app.overlineoverlineQ_FEditFieldLabel.Enable = 'off';
            app.overlineoverlineQ_FEditFieldLabel.Text = '$\overline{\overline{Q}}_F$';
            app.overlineoverlineQ_FEditField = uieditfield(app.SyntheticdatasetparametersPanel, 'text');
            app.overlineoverlineQ_FEditField.Enable = 'off';
            app.overlineoverlineQ_DEditFieldLabel = uilabel(app.SyntheticdatasetparametersPanel);
            app.overlineoverlineQ_DEditFieldLabel.Interpreter = 'latex';
            app.overlineoverlineQ_DEditFieldLabel.Enable = 'off';
            app.overlineoverlineQ_DEditFieldLabel.Text = '$\overline{\overline{Q}}_D$';
            app.overlineoverlineQ_DEditField = uieditfield(app.SyntheticdatasetparametersPanel, 'text');
            app.overlineoverlineQ_DEditField.Enable = 'off';
            app.overlineoverlineQ_AEditFieldLabel = uilabel(app.SyntheticdatasetparametersPanel);
            app.overlineoverlineQ_AEditFieldLabel.Interpreter = 'latex';
            app.overlineoverlineQ_AEditFieldLabel.Enable = 'off';
            app.overlineoverlineQ_AEditFieldLabel.Text = '$\overline{\overline{Q}}_A$';
            app.overlineoverlineQ_AEditField = uieditfield(app.SyntheticdatasetparametersPanel, 'text');
            app.overlineoverlineQ_AEditField.Enable = 'off';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.UIAxes_8 = uiaxes(app.ResultsPanel);
            xlabel(app.UIAxes_8, 'FRET transition rates')
            app.UIAxes_7 = uiaxes(app.ResultsPanel);
            xlabel(app.UIAxes_7, 'Acceptor-PIFE transition rates')
            app.UIAxes_6 = uiaxes(app.ResultsPanel);
            xlabel(app.UIAxes_6, 'Donor-PIFE transition rates')
            ylabel(app.UIAxes_6, 'Posterior prob. distri.')
            app.LicenseButton = uibutton(app.UIFigure, 'push');
            app.LicenseButton.ButtonPushedFcn = createCallbackFcn(app, @LicenseButtonPushed, true);
            app.LicenseButton.Text = 'License';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.FLsmFRETsmPIFEv11writtenbySinaJazanisjazani1jhueduLabel = uilabel(app.UIFigure);
            app.FLsmFRETsmPIFEv11writtenbySinaJazanisjazani1jhueduLabel.Text = {'FL-smFRET-smPIFE v1.1'; 'written by: Sina Jazani'; 'sjazani1@jhu.edu'};
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.ofdonorPIFEstatesLabel = uilabel(app.ParametersPanel);
            app.ofdonorPIFEstatesLabel.Enable = 'off';
            app.ofdonorPIFEstatesLabel.Text = {'# donor-PIFE '; 'states'};
            app.donorPIFEstatesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.donorPIFEstatesEditField.Limits = [1 10];
            app.donorPIFEstatesEditField.ValueDisplayFormat = '%.0f';
            app.donorPIFEstatesEditField.Enable = 'off';
            app.donorPIFEstatesEditField.Value = 1;
            app.ofacceptorPIFEstatesLabel = uilabel(app.ParametersPanel);
            app.ofacceptorPIFEstatesLabel.Enable = 'off';
            app.ofacceptorPIFEstatesLabel.Text = {'# acceptor-PIFE'; 'states'};
            app.acceptorPIFEstatesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.acceptorPIFEstatesEditField.Limits = [1 10];
            app.acceptorPIFEstatesEditField.ValueDisplayFormat = '%.0f';
            app.acceptorPIFEstatesEditField.Enable = 'off';
            app.acceptorPIFEstatesEditField.Value = 1;
            app.ofFRETstatesLabel = uilabel(app.ParametersPanel);
            app.ofFRETstatesLabel.Enable = 'off';
            app.ofFRETstatesLabel.Text = {'# FRET'; 'states'};
            app.FRETstatesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.FRETstatesEditField.Limits = [1 10];
            app.FRETstatesEditField.ValueDisplayFormat = '%.0f';
            app.FRETstatesEditField.Enable = 'off';
            app.FRETstatesEditField.Value = 1;
            app.RunPanel = uipanel(app.UIFigure);
            app.RunPanel.Title = 'Run';
            app.SavesizeEditFieldLabel = uilabel(app.RunPanel);
            app.SavesizeEditFieldLabel.Enable = 'off';
            app.SavesizeEditFieldLabel.Text = 'Save size';
            app.SavesizeEditField = uieditfield(app.RunPanel, 'numeric');
            app.SavesizeEditField.Limits = [1 10000];
            app.SavesizeEditField.ValueDisplayFormat = '%.0f';
            app.SavesizeEditField.Enable = 'off';
            app.SavesizeEditField.Value = 10;
            app.BurninEditFieldLabel = uilabel(app.RunPanel);
            app.BurninEditFieldLabel.Enable = 'off';
            app.BurninEditFieldLabel.Text = 'Burn-in %';
            app.BurninEditField = uieditfield(app.RunPanel, 'numeric');
            app.BurninEditField.Limits = [0 100];
            app.BurninEditField.Enable = 'off';
            app.BurninEditField.Value = 80;
            app.iterationsEditFieldLabel = uilabel(app.UIFigure);
            app.iterationsEditFieldLabel.Enable = 'off';
            app.iterationsEditFieldLabel.Text = '# iterations';
            app.iterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.iterationsEditField.Limits = [1 100000000];
            app.iterationsEditField.ValueDisplayFormat = '%.0f';
            app.iterationsEditField.Enable = 'off';
            app.iterationsEditField.Value = 100;
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Enable = 'off';
            app.ContinueButton.Text = 'Continue';
            app.Gauge = uigauge(app.UIFigure, 'linear');
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = 'File name: ';
            app.PreviouslyanalyzedfileButton = uibutton(app.UIFigure, 'push');
            app.PreviouslyanalyzedfileButton.ButtonPushedFcn = createCallbackFcn(app, @PreviouslyanalyzedfileButtonPushed, true);
            app.PreviouslyanalyzedfileButton.Text = 'Previously analyzed file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FL_smFRET_smPIFE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end